classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FITNESSappUIFigure            matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        WczytajButton                 matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        UITable                       matlab.ui.control.Table
        Button_2                      matlab.ui.control.Button
        ZapiszButton                  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        NickEditField                 matlab.ui.control.EditField
        ButtonGroup                   matlab.ui.container.ButtonGroup
        MczyznaButton                 matlab.ui.control.RadioButton
        KobietaButton                 matlab.ui.control.RadioButton
        NickEditFieldLabel            matlab.ui.control.Label
        NazwiskoEditField             matlab.ui.control.EditField
        NazwiskoEditFieldLabel        matlab.ui.control.Label
        ImiEditField                  matlab.ui.control.EditField
        ImiEditFieldLabel             matlab.ui.control.Label
        PrzedrozpoczciemzakontoLabel  matlab.ui.control.Label
        WitajLabel                    matlab.ui.control.Label
        Tab2                          matlab.ui.container.Tab
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        BMILamp                       matlab.ui.control.Lamp
        BMILampLabel                  matlab.ui.control.Label
        ObliczButton                  matlab.ui.control.Button
        PlanujeButtonGroup            matlab.ui.container.ButtonGroup
        PrzytyButton                  matlab.ui.control.RadioButton
        SchudnButton                  matlab.ui.control.RadioButton
        UtrzymamasButton              matlab.ui.control.RadioButton
        WiekEditField                 matlab.ui.control.NumericEditField
        WiekEditFieldLabel            matlab.ui.control.Label
        WzrostcmEditField             matlab.ui.control.NumericEditField
        WzrostcmEditFieldLabel        matlab.ui.control.Label
        WagakgEditField               matlab.ui.control.NumericEditField
        WagakgEditFieldLabel          matlab.ui.control.Label
        AktywnofizycznaButtonGroup    matlab.ui.container.ButtonGroup
        pracafizyczna5dniwtygodniu57treningwwtygodniuButton  matlab.ui.control.RadioButton
        redniaaktywno57treningwwtygodniuButton  matlab.ui.control.RadioButton
        treningwwtygodniuredniaaktywnoButton  matlab.ui.control.RadioButton
        ok3treningiwtygodniusiedzcytrybyciaButton  matlab.ui.control.RadioButton
        siedzcytrybycianiskaaktywnoButton  matlab.ui.control.RadioButton
        PeButtonGroup                 matlab.ui.container.ButtonGroup
        MczyznaButton_2               matlab.ui.control.RadioButton
        KobietaButton_2               matlab.ui.control.RadioButton
        ObliczswojeBMIizapotrzebowaniekaloryczneLabel  matlab.ui.control.Label
        Tab3                          matlab.ui.container.Tab
        AlicjaTab                     matlab.ui.container.Tab
        PokasiownieButton             matlab.ui.control.Button
        miastoButtonGroup             matlab.ui.container.ButtonGroup
        KrakwKazimierzButton          matlab.ui.control.RadioButton
        WarszawaBielanyButton         matlab.ui.control.RadioButton
        WarszawardmiecieButton        matlab.ui.control.RadioButton
        UITable2                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        t %mozliwosc uzywania tabeli w innych przyciskach ale teraz wszedzie dodajemy app.t
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick'); sprintf('Płeć K/M')};
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            global t
            imie = app.ImiEditField.Value
            nazw = app.NazwiskoEditField.Value
            nick = string(app.NickEditField.Value)
            
            %petla wybierajaca plec
            if app.KobietaButton.Value ==1 %warunek wybierajacy ktory rodzaj laty 
                plec = 'K';
            elseif app.MczyznaButton.Value ==1
                plec = 'M';
            end
            nr = {imie nazw nick plec}; %new row
            i=1
            dlugosc = numel(app.t(:,3))
            
            %while i <= dlugosc
            %    if nick == app.t(i,3)
            %        app.Lamp.Color = 'r';
            %        break
            %    else 
            %        app.Lamp.Color = 'g';
            %        
            %        i=i+1 
            %        continue
            %    end
            %end
            
%ta petla stwierdza czy nick sie powtarza
            if dlugosc ==0  %tu gdy mamy pusta tabelke na poczatku
                odp = 0;
                app.Lamp.Color = 'g';
            else
                for id=1:dlugosc
                    if nick == app.t{id,3} %cell array 
                        app.Lamp.Color = 'r';
                        odp = 1
                        break
                    else
                        app.Lamp.Color = 'g';
                        odp = 0
                        continue                      
                   end
                end
            end           
%ta petla na podstawie poprzedniej decyduje czy dodajemy nowy wiersz czy nie 
            if odp == 1     %jesli odp = nie to nie robimy nic
                app.Label.Text = 'Taki Nick już istnieje.';
                app.Label_2.Text = 'Użyj innego.';
                return
            else           %w innym wypadku dodajemy to tablicy new row
                app.Label.Text = 'Wybrany przez ciebie Nick jest wolny.' ;
                app.Label_2.Text = 'Udało się!';
                app.UITable.Data = [app.t ; nr];
                app.t = app.UITable.Data;
            end
            tabela = readtable('dane.xlsx','Sheet',1);
            newtable = table()
            newtable.Imie = [imie]
            newtable.Nazwisko = [nazw]
            newtable.Nick = [nick]
            newtable.P_e_ = [plec]
            tabela = [tabela; newtable]
            writetable(tabela,'dane.xlsx')
        ala=21
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick')};
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: PokasiownieButton
        function PokasiownieButtonPushed(app, event)
             %Warszawa = [52.2297700, 21.0117800];
            %Gdansk = [54.3520500, 18.6463700];
            %Krakow = [50.0614300, 19.9365800];
            
            silownia1= [52.25723, 20.98457351, "ZDROFIT", "al. Jana Pawła II 82, CH ARKADIA"]  ;
            silownia2= [52.232879, 20.999556, "ZDROFIT", "ul. RONDO ONZ 1"] ;
            silownia3= [52.2941895, 20.9303168, "ZDROFIT", "ul. Zgrupowania AK 'Kampinos' 15, GALERIA MŁOCINY"] ;
            silownia4= [52.2912362, 20.9363057, "ZDROFIT" "ul. Przy Agorze 22"];
            silownia5=["Siłownia Kulturystyczna CHAMPION", "ul. Księdza Augustyna Kordeckiego 7"];
            silownia6=["Atlantic Squash & Fitness","ul. Stradomska 15"];
            silownia7=["fitLady", "ul. Marii Konopnickiej 5"];
            silownia8=["EMS 2020", "ul. Antoniego Józefa Madalińskiego 11/3"];
            silownia9=["ZDROFIT", "al. Grunwaldzka 82, CH MANHATTAN"];
            silownia10=["Tonezone", "ul. Jana Kilińskiego 4"];
            silownia11=["CityFit", "Targ Sienny 7"];
            silownia12=["Yoga Compass", "ul. Wojciecha Bogusławskiego 1"];
            
            
            WarszawaBielany=[silownia3(3), silownia3(4); silownia4(3), silownia4(4)];
            WarszawaSrodmiescie=[silownia1(3), silownia1(4); silownia2(3), silownia2(4)];
            KrakowKazimierz=[silownia5(1), silownia5(2); silownia6(1), silownia6(2)];
            KrakowDebniki=[silownia7(1), silownia7(2); silownia8(1), silownia8(2)];
            GdanskWrzeszcz=[silownia9(1), silownia9(2); silownia10(1), silownia10(2)];
            GdanskSrodmiescie=[silownia11(1), silownia11(2); silownia12(1), silownia12(2)];
            
%             if app.GdaskWrzeszczButton.Value == 1
%                 tabela=table(GdanskWrzeszcz);
%             elseif app.GdaskrdmiecieButton.Value == 1
%                 tabela=table(GdanskSrodmiescie);
            if app.WarszawardmiecieButton.Value == 1
                tabela=table(WarszawaSrodmiescie);
            elseif app.KrakwKazimierzButton.Value == 1
                tabela=table(KrakowKazimierz);
            elseif app.WarszawaBielanyButton.Value == 1
                tabela=table(WarszawaBielany);
%             elseif app.KrakwDbnikiButton.Value == 1
%                 tabela=table(KrakowDebniki);
            end
            
            app.UITable2.Data=tabela;
            app.UITable2.Visible='on';
            app.miastoButtonGroup.Scrollable='on';
            
        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
              waga = app.WagakgEditField.Value
            wzrost = app.WzrostcmEditField.Value
            wiek = app.WiekEditField.Value
            wzrost_m = wzrost/100
            
           
            %obliczam bmi
            BMI = waga/((wzrost_m).^2)
            
        if app.KobietaButton.Value == 1
            if wiek>=18 && wiek<24
                if BMI>=19 && BMI<24
                    app.BMILamp.Color = 'g';
                    app.Label_4.Text = ''
                elseif BMI>=24 && BMI<34, BMI>=15 && BMI<19
                    app.BMILamp.Color = 'y';
                elseif BMI<15
                    app.BMILamp.Color = 'r';
                elseif BMI>=34 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=24 && wiek<34
                if BMI>=20 && BMI<25
                    app.BMILamp.Color = 'g';
                elseif BMI>=25 && BMI<35, BMI>=16 && BMI<20
                    app.BMILamp.Color = 'y';
                elseif BMI<16, BMI>=35 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=34 && wiek<44
                if BMI>=21 && BMI<26
                    app.BMILamp.Color = 'g';
                elseif BMI>=26 && BMI<36, BMI>=17 && BMI<21
                    app.BMILamp.Color = 'y';
                elseif BMI<17, BMI>=36 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=44 && wiek<54
                if BMI>=22 && BMI<27
                    app.BMILamp.Color = 'g';
                elseif BMI>=27 && BMI<37, BMI>=18 && BMI<22
                    app.BMILamp.Color = 'y';
                elseif BMI<18, BMI>=37 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=54 && wiek<64
                if BMI>=23 && BMI<28
                    app.BMILamp.Color = 'g';
                elseif BMI>=28 && BMI<38, BMI>=19 && BMI<23
                    app.BMILamp.Color = 'y';
                elseif BMI<19, BMI>=38 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=64 
                if BMI>=24 && BMI<29
                    app.BMILamp.Color = 'g';
                elseif BMI>=29 && BMI<39, BMI>=20 && BMI<24
                    app.BMILamp.Color = 'y';
                elseif BMI<20, BMI>=39 
                    app.BMILamp.Color = 'r';
                end
            end
        elseif app.MczyznaButton.Value == 1
            if wiek>=18 && wiek<24
                if BMI>=20 && BMI<25
                    app.BMILamp.Color = 'g';
                elseif BMI>=25 && BMI<35, BMI>=16 && BMI<20
                    app.BMILamp.Color = 'y';
                elseif BMI<16, BMI>=35 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=24 && wiek<34
                if BMI>=21 && BMI<26
                    app.BMILamp.Color = 'g';
                elseif BMI>=26 && BMI<36, BMI>=17 && BMI<21
                    app.BMILamp.Color = 'y';
                elseif BMI<17, BMI>=36
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=34 && wiek<44
                if BMI>=22 && BMI<27
                    app.BMILamp.Color = 'g';
                elseif BMI>=27 && BMI<37, BMI>=18 && BMI<22
                    app.BMILamp.Color = 'y';
                elseif BMI<18, BMI>=37 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=44 && wiek<54
                if BMI>=23 && BMI<28
                    app.BMILamp.Color = 'g';
                elseif BMI>=28 && BMI<38, BMI>=19 && BMI<23
                    app.BMILamp.Color = 'y';
                elseif BMI<19, BMI>=38 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=54 && wiek<64
                if BMI>=24 && BMI<29
                    app.BMILamp.Color = 'g';
                elseif BMI>=29 && BMI<39, BMI>=20 && BMI<24
                    app.BMILamp.Color = 'y';
                elseif BMI<20, BMI>=39 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=64 
                if BMI>=25 && BMI<30
                    app.BMILamp.Color = 'g';
                elseif BMI>=30 && BMI<40, BMI>=21 && BMI<25
                    app.BMILamp.Color = 'y';
                elseif BMI<21, BMI>=40 
                    app.BMILamp.Color = 'r';
                end
            end
        end


            %podstawowa przemiaii
            if app.KobietaButton.Value == 1
                PPM = (10*waga)+(6.25*wzrost)-(5*wiek)-161;
            elseif app.MczyznaButton.Value == 1
                PPM = (10*waga)+(6.25*wzrost)-(5*wiek)+5;
            end
            
            %zapotrzebowanie kaloryczne
            if app.siedzcytrybycianiskaaktywnoButton.Value == 1
                ZK = PPM*1.2;
            elseif app.ok3treningiwtygodniusiedzcytrybyciaButton.Value == 1
                ZK = PPM*1.375;
            elseif app.treningwwtygodniuredniaaktywnoButton.Value == 1
                ZK = PPM*1.55;
            elseif app.redniaaktywno57treningwwtygodniuButton.Value == 1
                ZK = PPM*1.715;
            elseif app.pracafizyczna5dniwtygodniu57treningwwtygodniuButton.Value ==1
                ZK = PPM*1.9;
            end
            
            %zapotrzebowanie kaloryczne zgodnie z planami
            if app.UtrzymamasButton.Value == 1
               ZKA = ZK;
            elseif app.SchudnButton.Value == 1
                ZKA = ZK-((ZK/100)*15);
            elseif app.PrzytyButton.Value == 1
                ZKA = ZK+225;
            end
            
           A1 = 'Twoje BMI: ' 
           A2 = 'Twoje zapotrzebowanie kaloryczne: ' 
           A3 = 'Obliczone wartości'
           
           app.Label_3.Text = sprintf(A3);
           app.Label_3.FontSize = 18;
           app.Label_3.Visible ='on';
           
           app.Label_4.Text = sprintf('%s', A1, num2str(BMI));
           app.Label_4.FontSize = 16;
           app.Label_4.Visible ='on';
           
           app.Label_5.Text = sprintf('%s', A2, num2str(ZKA));
           app.Label_5.FontSize = 16;
           app.Label_5.Visible ='on';
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FITNESSappUIFigure = uifigure('Visible', 'off');
            app.FITNESSappUIFigure.Name = 'FITNESSapp';
            app.TabGroup = uitabgroup(app.FITNESSappUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.WitajLabel = uilabel(app.Tab);
            app.WitajLabel.Text = 'Witaj!  ';
            app.PrzedrozpoczciemzakontoLabel = uilabel(app.Tab);
            app.PrzedrozpoczciemzakontoLabel.Text = 'Przed rozpoczęciem - załóż konto:';
            app.Panel = uipanel(app.Tab);
            app.ImiEditFieldLabel = uilabel(app.Panel);
            app.ImiEditFieldLabel.Text = 'Imię';
            app.ImiEditField = uieditfield(app.Panel, 'text');
            app.NazwiskoEditFieldLabel = uilabel(app.Panel);
            app.NazwiskoEditFieldLabel.Text = 'Nazwisko';
            app.NazwiskoEditField = uieditfield(app.Panel, 'text');
            app.NickEditFieldLabel = uilabel(app.Panel);
            app.NickEditFieldLabel.Text = 'Nick';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.KobietaButton = uiradiobutton(app.ButtonGroup);
            app.KobietaButton.Text = 'Kobieta';
            app.KobietaButton.Value = true;
            app.MczyznaButton = uiradiobutton(app.ButtonGroup);
            app.MczyznaButton.Text = 'Mężczyzna';
            app.NickEditField = uieditfield(app.Panel, 'text');
            app.ZapiszButton = uibutton(app.Tab, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Icon = 'zapisz.png';
            app.ZapiszButton.Text = 'Zapisz';
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.Icon = 'LOGO.jpg';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.Tab);
            app.Button = uibutton(app.Tab, 'push');
            app.Button.Icon = 'LOGO.jpg';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '';
            app.WczytajButton = uibutton(app.Tab, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Visible = 'off';
            app.WczytajButton.Text = 'Wczytaj';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ObliczswojeBMIizapotrzebowaniekaloryczneLabel = uilabel(app.Tab2);
            app.ObliczswojeBMIizapotrzebowaniekaloryczneLabel.Text = 'Oblicz swoje BMI i zapotrzebowanie kaloryczne';
            app.PeButtonGroup = uibuttongroup(app.Tab2);
            app.PeButtonGroup.Title = 'Płeć';
            app.KobietaButton_2 = uiradiobutton(app.PeButtonGroup);
            app.KobietaButton_2.Text = 'Kobieta';
            app.KobietaButton_2.Value = true;
            app.MczyznaButton_2 = uiradiobutton(app.PeButtonGroup);
            app.MczyznaButton_2.Text = 'Mężczyzna';
            app.AktywnofizycznaButtonGroup = uibuttongroup(app.Tab2);
            app.AktywnofizycznaButtonGroup.Title = 'Aktywność fizyczna';
            app.siedzcytrybycianiskaaktywnoButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.siedzcytrybycianiskaaktywnoButton.Text = 'siedzący tryb życia, niska aktywność';
            app.siedzcytrybycianiskaaktywnoButton.Value = true;
            app.ok3treningiwtygodniusiedzcytrybyciaButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.ok3treningiwtygodniusiedzcytrybyciaButton.Text = 'ok.3 treningi w tygodniu, siedzący tryb życia';
            app.treningwwtygodniuredniaaktywnoButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.treningwwtygodniuredniaaktywnoButton.Text = '4-5 treningów w tygodniu, średnia aktywność';
            app.redniaaktywno57treningwwtygodniuButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.redniaaktywno57treningwwtygodniuButton.Text = 'średnia aktywność, 5-7 treningów  w tygodniu';
            app.pracafizyczna5dniwtygodniu57treningwwtygodniuButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.pracafizyczna5dniwtygodniu57treningwwtygodniuButton.Text = 'praca fizyczna 5 dni w tygodniu, 5-7 treningów w tygodniu';
            app.WagakgEditFieldLabel = uilabel(app.Tab2);
            app.WagakgEditFieldLabel.Text = 'Waga [kg]';
            app.WagakgEditField = uieditfield(app.Tab2, 'numeric');
            app.WzrostcmEditFieldLabel = uilabel(app.Tab2);
            app.WzrostcmEditFieldLabel.Text = 'Wzrost [cm]';
            app.WzrostcmEditField = uieditfield(app.Tab2, 'numeric');
            app.WiekEditFieldLabel = uilabel(app.Tab2);
            app.WiekEditFieldLabel.Text = 'Wiek';
            app.WiekEditField = uieditfield(app.Tab2, 'numeric');
            app.PlanujeButtonGroup = uibuttongroup(app.Tab2);
            app.PlanujeButtonGroup.Title = 'Planuje';
            app.UtrzymamasButton = uiradiobutton(app.PlanujeButtonGroup);
            app.UtrzymamasButton.Text = 'Utrzymać masę';
            app.UtrzymamasButton.Value = true;
            app.SchudnButton = uiradiobutton(app.PlanujeButtonGroup);
            app.SchudnButton.Text = 'Schudnąć';
            app.PrzytyButton = uiradiobutton(app.PlanujeButtonGroup);
            app.PrzytyButton.Text = 'Przytyć';
            app.ObliczButton = uibutton(app.Tab2, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.BMILampLabel = uilabel(app.Tab2);
            app.BMILampLabel.Text = 'BMI';
            app.BMILamp = uilamp(app.Tab2);
            app.Label_3 = uilabel(app.Tab2);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.Tab2);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.Tab2);
            app.Label_5.Text = '';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.AlicjaTab = uitab(app.TabGroup);
            app.AlicjaTab.Title = 'Alicja';
            app.UITable2 = uitable(app.AlicjaTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.miastoButtonGroup = uibuttongroup(app.AlicjaTab);
            app.miastoButtonGroup.Title = 'miasto';
            app.WarszawardmiecieButton = uiradiobutton(app.miastoButtonGroup);
            app.WarszawardmiecieButton.Text = 'Warszawa Śródmieście';
            app.WarszawardmiecieButton.Value = true;
            app.WarszawaBielanyButton = uiradiobutton(app.miastoButtonGroup);
            app.WarszawaBielanyButton.Text = 'Warszawa Bielany';
            app.KrakwKazimierzButton = uiradiobutton(app.miastoButtonGroup);
            app.KrakwKazimierzButton.Text = 'Kraków Kazimierz';
            app.PokasiownieButton = uibutton(app.AlicjaTab, 'push');
            app.PokasiownieButton.ButtonPushedFcn = createCallbackFcn(app, @PokasiownieButtonPushed, true);
            app.PokasiownieButton.Text = 'Pokaż siłownie';
            % Show the figure after all components are created
            app.FITNESSappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FITNESSappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FITNESSappUIFigure)
        end
    end
end