classdef app1mati < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FITNESSappUIFigure            matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LogowanieTab                  matlab.ui.container.Tab
        WitajLabel                    matlab.ui.control.Label
        PrzedrozpoczciemzakontoLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ImiEditFieldLabel             matlab.ui.control.Label
        ImiEditField                  matlab.ui.control.EditField
        NazwiskoEditFieldLabel        matlab.ui.control.Label
        NazwiskoEditField             matlab.ui.control.EditField
        NickEditFieldLabel            matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        KobietaButton                 matlab.ui.control.RadioButton
        MczyznaButton                 matlab.ui.control.RadioButton
        NickEditField                 matlab.ui.control.EditField
        ZapiszButton                  matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Lamp                          matlab.ui.control.Lamp
        Button                        matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        WczytajButton                 matlab.ui.control.Button
        BMITab                        matlab.ui.container.Tab
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        BMILamp                       matlab.ui.control.Lamp
        ObliczButton                  matlab.ui.control.Button
        PlanujeButtonGroup            matlab.ui.container.ButtonGroup
        PrzytyButton                  matlab.ui.control.RadioButton
        SchudncButton                 matlab.ui.control.RadioButton
        UtrzymamasButton              matlab.ui.control.RadioButton
        AktywnofizycznaButtonGroup    matlab.ui.container.ButtonGroup
        pracafizyczna5dniwtygitreningi57razyButton  matlab.ui.control.RadioButton
        niskaaktywnosiedzcytrybyciaButton  matlab.ui.control.RadioButton
        redniaaktywnotreningi35razywtygodniuButton  matlab.ui.control.RadioButton
        wysokaaktywnotreningi57razywtygodniuButton  matlab.ui.control.RadioButton
        redniaaktywnospacerytrening2razywtygButton  matlab.ui.control.RadioButton
        BMILampLabel                  matlab.ui.control.Label
        PeButtonGroup                 matlab.ui.container.ButtonGroup
        MczyznaButton_2               matlab.ui.control.RadioButton
        KobietaButton_2               matlab.ui.control.RadioButton
        ObliczswojeBMIizapotrzebowaniekaloryczneLabel  matlab.ui.control.Label
        WagakgLabel                   matlab.ui.control.Label
        WagakgEditField               matlab.ui.control.NumericEditField
        WzrostcmEditFieldLabel        matlab.ui.control.Label
        WzrostcmEditField             matlab.ui.control.NumericEditField
        WiekEditFieldLabel            matlab.ui.control.Label
        WiekEditField                 matlab.ui.control.NumericEditField
        MenuTab                       matlab.ui.container.Tab
        PrzykadowyjadospisdlaTwojegozapotrzebowaniakalorycznegoLabel  matlab.ui.control.Label
        PokamenuButton                matlab.ui.control.Button
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        AlicjaTab                     matlab.ui.container.Tab
        UITable2                      matlab.ui.control.Table
        miastoButtonGroup             matlab.ui.container.ButtonGroup
        WarszawardmiecieButton        matlab.ui.control.RadioButton
        WarszawaBielanyButton         matlab.ui.control.RadioButton
        KrakwKazimierzButton          matlab.ui.control.RadioButton
        PokasiownieButton             matlab.ui.control.Button
        WybierzdzielnicDropDownLabel  matlab.ui.control.Label
        WybierzdzielnicDropDown       matlab.ui.control.DropDown
        RankingTab                    matlab.ui.container.Tab
        RANKINGUYTKOWNIKWLabel        matlab.ui.control.Label
        PodajswjnickEditFieldLabel    matlab.ui.control.Label
        PodajswjnickEditField         matlab.ui.control.EditField
        Lamp_R                        matlab.ui.control.Lamp
        IlekilogramwudaoCisischudnLabel  matlab.ui.control.Label
        ilKg                          matlab.ui.control.NumericEditField
        PokawynikiButton              matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        UITable3                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        t %mozliwosc uzywania tabeli w innych przyciskach ale teraz wszedzie dodajemy app.t
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick'); sprintf('Płeć K/M')};
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            global t
            imie = app.ImiEditField.Value
            nazw = app.NazwiskoEditField.Value
            nick = string(app.NickEditField.Value)
            
            %petla wybierajaca plec
            if app.KobietaButton.Value ==1 %warunek wybierajacy ktory rodzaj laty 
                plec = 'K';
            elseif app.MczyznaButton.Value ==1
                plec = 'M';
            end
            nr = {imie nazw nick plec}; %new row
            i=1
            dlugosc = numel(app.t(:,3))
            
            %while i <= dlugosc
            %    if nick == app.t(i,3)
            %        app.Lamp.Color = 'r';
            %        break
            %    else 
            %        app.Lamp.Color = 'g';
            %        
            %        i=i+1 
            %        continue
            %    end
            %end
            
%ta petla stwierdza czy nick sie powtarza
            if dlugosc ==0  %tu gdy mamy pusta tabelke na poczatku
                odp = 0;
                app.Lamp.Color = 'g';
            else
                for id=1:dlugosc
                    if nick == app.t{id,3} %cell array 
                        app.Lamp.Color = 'r';
                        odp = 1
                        break
                    else
                        app.Lamp.Color = 'g';
                        odp = 0
                        continue                      
                   end
                end
            end           
%ta petla na podstawie poprzedniej decyduje czy dodajemy nowy wiersz czy nie 
            if odp == 1     %jesli odp = nie to nie robimy nic
                app.Label.Text = 'Taki Nick już istnieje.';
                app.Label_2.Text = 'Użyj innego.';
                return
            else           %w innym wypadku dodajemy to tablicy new row
                app.Label.Text = 'Wybrany przez ciebie Nick jest wolny.' ;
                app.Label_2.Text = 'Udało się!';
                app.UITable.Data = [app.t ; nr];
                app.t = app.UITable.Data;
            end
            tabela = readtable('dane.xlsx','Sheet',1);
            newtable = table()
            newtable.Imie = [imie]
            newtable.Nazwisko = [nazw]
            newtable.Nick = [nick]
            newtable.P_e_ = [plec]
            tabela = [tabela; newtable]
            writetable(tabela,'dane.xlsx')
        ala=21
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick')};
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: PokasiownieButton
        function PokasiownieButtonPushed(app, event)
            
            asd=readtable('silownie.xlsx');
            dlug = (asd.X);
            szer=(asd.Y);
            adr=asd.adres;
            naz=asd.nazwa;
            
%             silownia1= [52.25723, 20.98457351, "ZDROFIT", "al. Jana Pawła II 82, CH ARKADIA"]  ;
%             silownia2= [52.232879, 20.999556, "ZDROFIT", "ul. RONDO ONZ 1"] ;
%             silownia3= [52.2941895, 20.9303168, "ZDROFIT", "ul. Zgrupowania AK 'Kampinos' 15, GALERIA MŁOCINY"] ;
%             silownia4= [52.2912362, 20.9363057, "ZDROFIT" "ul. Przy Agorze 22"];
%             silownia5=["Siłownia Kulturystyczna CHAMPION", "ul. Księdza Augustyna Kordeckiego 7"];
%             silownia6=["Atlantic Squash & Fitness","ul. Stradomska 15"];
%             silownia7=["fitLady", "ul. Marii Konopnickiej 5"];
%             silownia8=["EMS 2020", "ul. Antoniego Józefa Madalińskiego 11/3"];
%             silownia9=["ZDROFIT", "al. Grunwaldzka 82, CH MANHATTAN"];
%             silownia10=["Tonezone", "ul. Jana Kilińskiego 4"];
%             silownia11=["CityFit", "Targ Sienny 7"];
%             silownia12=["Yoga Compass", "ul. Wojciecha Bogusławskiego 1"];
            
            
%             WarszawaBielany=[silownia3(3), silownia3(4); silownia4(3), silownia4(4)];
%             WarszawaSrodmiescie=[silownia1(3), silownia1(4); silownia2(3), silownia2(4)];
%             KrakowKazimierz=[silownia5(1), silownia5(2); silownia6(1), silownia6(2)];
%             KrakowDebniki=[silownia7(1), silownia7(2); silownia8(1), silownia8(2)];
%             GdanskWrzeszcz=[silownia9(1), silownia9(2); silownia10(1), silownia10(2)];
%             GdanskSrodmiescie=[silownia11(1), silownia11(2); silownia12(1), silownia12(2)];
%             app.UITable2.Data=tabela;
%             app.UITable2.Visible='on';
            
% app.miastoButtonGroup.Scrollable='on';   
% 
%             if app.WybierzdzielnicDropDown.Value == 'Bemowo'
%                 table(naz(11:19), adr(11:19));
%                 geoscatter([dlug(11:19)], [szer(11:19)],'*')
%             if app.WybierzdzielnicDropDown.Value == 'Białołęka'
%                 tabela=table(naz(20:28), adr(20:28));
%                 geoscatter([dlug(20:28)], [szer(20:28)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Bielany'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Mokotów'
%                 tabela=table(naz(29:37), adr(29:37));
%                 geoscatter([dlug(29:37)], [szer(29:37)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Ochota'
%                 tabela=table(naz(38:46), adr(38:46));
%                 geoscatter([dlug(38:46)], [szer(38:46)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Praga-Południe'
%                 tabela=table(naz(47:55), adr(47:55));
%                 geoscatter([dlug(47:55)], [szer(47:55)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Praga-Północ'
%                 tabela=table(naz(56:64), adr(56:64));
%                 geoscatter([dlug(56:64)], [szer(56:64)],'*')    
%             elseif app.WybierzdzielnicDropDown.Value == 'Rembertów'
%                 tabela=table(naz(65:69), adr(65:69));
%                 geoscatter([dlug(65:69)], [szer(65:69)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Śródmieście'
%                 tabela=table(naz(70:78), adr(70:78));
%                 geoscatter([dlug(70:78)], [szer(70:78)],'*') 
%             elseif app.WybierzdzielnicDropDown.Value == 'Targówek'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Ursus'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Ursynów'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')    
%             elseif app.WybierzdzielnicDropDown.Value == 'Wawer'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Wesoła'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Wilanów'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Włochy'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             elseif app.WybierzdzielnicDropDown.Value == 'Wola'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')    
%             elseif app.WybierzdzielnicDropDown.Value == 'Żoliborz'
%                 tabela=table(naz(2:10), adr(2:10));
%                 geoscatter([dlug(2:10)], [szer(2:10)],'*')
%             end
%             
%             app.UITable2.Data=tabela;
%             app.UITable2.Visible='on';
%             app.miastoButtonGroup.Scrollable='on';
%             
        end

        % Button pushed function: PokawynikiButton
        function PokawynikiButtonPushed(app, event)
            global t
            nick1 = string(app.PodajswjnickEditField.Value)
            ilKg = app.ilKg.Value
            dlugosc = numel(app.t(:,3))
            
            for id=1:dlugosc
                    if nick1 == app.t{id,3} 
                       app.Lamp_R.Color = 'g';
                       warunek_T_N = 0
                       break
                    else
                       app.Lamp_R.Color = 'r';
                       warunek_T_N = 1
                       continue                      
                   end
            end           
            if warunek_T_N == 0
                tabelaR = readtable('ranking.xlsx','Sheet',1);
                newtableR = table()
                newtableR.Nick = [nick1]
                newtableR.IloscKilogramow = [ilKg]
                tabelaR = [tabelaR; newtableR] 
                tabelasort = sortrows(tabelaR,{'IloscKilogramow'},{'descend'})
                app.UITable3.Data=tabelasort;
                app.UITable3.Visible='on';
                writetable(tabelaR,'ranking.xlsx')
            else 
                app.Label_3.Text = ['Nie ma takiego użytkownika. ' ...
                    'Załóż konto.'];
            end
            

        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            waga = app.WagakgEditField.Value
            wzrost = app.WzrostcmEditField.Value
            wiek = app.WiekEditField.Value
            wzrost_m = wzrost/100
%             waga1=app.wagaEditField.Value
%             wzrost2=app.wzrostEditField.Value
%             wiek2=app.wieczekEditField.Value
%            
            %obliczam bmi
            BMI = (waga)/((wzrost_m).^2)
            
        if app.KobietaButton.Value == 1
            if wiek>=18 && wiek<24
                if BMI>=19 && BMI<24
                    app.BMILamp.Color = 'g';
                    app.Label_4.Text = ''
                elseif BMI>=24 && BMI<34, BMI>=15 && BMI<19
                    app.BMILamp.Color = 'y';
                elseif BMI<15
                    app.BMILamp.Color = 'r';
                elseif BMI>=34 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=24 && wiek<34
                if BMI>=20 && BMI<25
                    app.BMILamp.Color = 'g';
                elseif BMI>=25 && BMI<35, BMI>=16 && BMI<20
                    app.BMILamp.Color = 'y';
                elseif BMI<16, BMI>=35 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=34 && wiek<44
                if BMI>=21 && BMI<26
                    app.BMILamp.Color = 'g';
                elseif BMI>=26 && BMI<36, BMI>=17 && BMI<21
                    app.BMILamp.Color = 'y';
                elseif BMI<17, BMI>=36 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=44 && wiek<54
                if BMI>=22 && BMI<27
                    app.BMILamp.Color = 'g';
                elseif BMI>=27 && BMI<37, BMI>=18 && BMI<22
                    app.BMILamp.Color = 'y';
                elseif BMI<18, BMI>=37 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=54 && wiek<64
                if BMI>=23 && BMI<28
                    app.BMILamp.Color = 'g';
                elseif BMI>=28 && BMI<38, BMI>=19 && BMI<23
                    app.BMILamp.Color = 'y';
                elseif BMI<19, BMI>=38 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=64 
                if BMI>=24 && BMI<29
                    app.BMILamp.Color = 'g';
                elseif BMI>=29 && BMI<39, BMI>=20 && BMI<24
                    app.BMILamp.Color = 'y';
                elseif BMI<20, BMI>=39 
                    app.BMILamp.Color = 'r';
                end
            end
        elseif app.MczyznaButton.Value == 1
            if wiek>=18 && wiek<24
                if BMI>=20 && BMI<25
                    app.BMILamp.Color = 'g';
                elseif BMI>=25 && BMI<35, BMI>=16 && BMI<20
                    app.BMILamp.Color = 'y';
                elseif BMI<16, BMI>=35 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=24 && wiek<34
                if BMI>=21 && BMI<26
                    app.BMILamp.Color = 'g';
                elseif BMI>=26 && BMI<36, BMI>=17 && BMI<21
                    app.BMILamp.Color = 'y';
                elseif BMI<17, BMI>=36
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=34 && wiek<44
                if BMI>=22 && BMI<27
                    app.BMILamp.Color = 'g';
                elseif BMI>=27 && BMI<37, BMI>=18 && BMI<22
                    app.BMILamp.Color = 'y';
                elseif BMI<18, BMI>=37 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=44 && wiek<54
                if BMI>=23 && BMI<28
                    app.BMILamp.Color = 'g';
                elseif BMI>=28 && BMI<38, BMI>=19 && BMI<23
                    app.BMILamp.Color = 'y';
                elseif BMI<19, BMI>=38 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=54 && wiek<64
                if BMI>=24 && BMI<29
                    app.BMILamp.Color = 'g';
                elseif BMI>=29 && BMI<39, BMI>=20 && BMI<24
                    app.BMILamp.Color = 'y';
                elseif BMI<20, BMI>=39 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=64 
                if BMI>=25 && BMI<30
                    app.BMILamp.Color = 'g';
                elseif BMI>=30 && BMI<40, BMI>=21 && BMI<25
                    app.BMILamp.Color = 'y';
                elseif BMI<21, BMI>=40 
                    app.BMILamp.Color = 'r';
                end
            end
        end
            
            global ZKA
            
            %podstawowa przemiaii
            if app.KobietaButton.Value == 1
                PPM = (10*waga)+(6.25*wzrost)-(5*wiek)-161;
            elseif app.MczyznaButton.Value == 1
                PPM = (10*waga)+(6.25*wzrost)-(5*wiek)+5;
            end
            
            %zapotrzebowanie kaloryczne
            if app.niskaaktywnosiedzcytrybyciaButton.Value == 1
                ZK = PPM*1.2;
            elseif app.redniaaktywnospacerytrening2razywtygButton.Value == 1
                ZK = PPM*1.375;
            elseif app.redniaaktywnotreningi35razywtygodniuButton.Value == 1
                ZK = PPM*1.55;
            elseif app.wysokaaktywnotreningi57razywtygodniuButton.Value == 1
                ZK = PPM*1.715;
            elseif app.pracafizyczna5dniwtygitreningi57razyButton.Value ==1
                ZK = PPM*1.9;
            end
            
            %zapotrzebowanie kaloryczne zgodnie z planami
            if app.UtrzymamasButton.Value == 1
               ZKA = ZK;
            elseif app.SchudnButton.Value == 1
                ZKA = ZK-((ZK/100)*15);
            elseif app.PrzytyButton.Value == 1
                ZKA = ZK+225;
            end
            
           A1 = 'Twoje BMI: ' 
           A2 = 'Twoje zapotrzebowanie kcal: ' 
           A3 = 'Obliczone wartości'
           
           app.Label_4.Text = sprintf(A3);
           app.Label_4.FontSize = 18;
           app.Label_4.Visible ='on';
           
           app.Label_5.Text = sprintf('%s', A1, num2str(BMI));
           app.Label_5.FontSize = 15;
           app.Label_5.Visible ='on';
           
           app.Label_6.Text = sprintf('%s', A2, num2str(ZKA));
           app.Label_6.FontSize = 15;
           app.Label_6.Visible ='on'; 
        end

        % Button pushed function: PokamenuButton
        function PokamenuButtonPushed(app, event)
                       

            tabela = readtable('menu.xlsx')
zp1 = tabela.L_kalorii;
menu = tabela.menu;
menu2 = tabela.menu2;
menu3 = tabela.menu3;
menu4 = tabela.menu4;
menu5 = tabela.menu5;


%liczone zapotrzebowanie kaloryczne - zmiennna "szukane"
szukane = ZKA
i = floor(szukane/100)*100;
p = 1;
while i > zp1(p)
    p = p + 1;
end

jadlospis = menu(p);
jadlospis2 = menu2(p);
jadlospis3 = menu3(p);
jadlospis4 = menu4(p);
jadlospis5 = menu5(p);

app.Label.Text = jadlospis;
app.Label.Visible ='on';
app.Label_2.Text = jadlospis2;
app.Label_2.Visible ='on';
app.Label_3.Text = jadlospis3;
app.Label_3.Visible ='on';
app.Label_4.Text = jadlospis4;
app.Label_4.Visible ='on';
app.Label_5.Text = jadlospis5;
app.Label_5.Visible ='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FITNESSappUIFigure = uifigure('Visible', 'off');
            app.FITNESSappUIFigure.Name = 'FITNESSapp';
            app.TabGroup = uitabgroup(app.FITNESSappUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LogowanieTab = uitab(app.TabGroup);
            app.LogowanieTab.Title = 'Logowanie';
            app.WitajLabel = uilabel(app.LogowanieTab);
            app.WitajLabel.Text = 'Witaj!  ';
            app.PrzedrozpoczciemzakontoLabel = uilabel(app.LogowanieTab);
            app.PrzedrozpoczciemzakontoLabel.Text = 'Przed rozpoczęciem - załóż konto:';
            app.Panel = uipanel(app.LogowanieTab);
            app.ImiEditFieldLabel = uilabel(app.Panel);
            app.ImiEditFieldLabel.Text = 'Imię';
            app.ImiEditField = uieditfield(app.Panel, 'text');
            app.NazwiskoEditFieldLabel = uilabel(app.Panel);
            app.NazwiskoEditFieldLabel.Text = 'Nazwisko';
            app.NazwiskoEditField = uieditfield(app.Panel, 'text');
            app.NickEditFieldLabel = uilabel(app.Panel);
            app.NickEditFieldLabel.Text = 'Nick';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.KobietaButton = uiradiobutton(app.ButtonGroup);
            app.KobietaButton.Text = 'Kobieta';
            app.KobietaButton.Value = true;
            app.MczyznaButton = uiradiobutton(app.ButtonGroup);
            app.MczyznaButton.Text = 'Mężczyzna';
            app.NickEditField = uieditfield(app.Panel, 'text');
            app.ZapiszButton = uibutton(app.LogowanieTab, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Icon = 'zapisz.png';
            app.ZapiszButton.Text = 'Zapisz';
            app.Button_2 = uibutton(app.LogowanieTab, 'push');
            app.Button_2.Icon = 'LOGO.jpg';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.UITable = uitable(app.LogowanieTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.LogowanieTab);
            app.Button = uibutton(app.LogowanieTab, 'push');
            app.Button.Icon = 'LOGO.jpg';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Label_2 = uilabel(app.LogowanieTab);
            app.Label_2.Text = '';
            app.Label = uilabel(app.LogowanieTab);
            app.Label.Text = '';
            app.WczytajButton = uibutton(app.LogowanieTab, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Visible = 'off';
            app.WczytajButton.Text = 'Wczytaj';
            app.BMITab = uitab(app.TabGroup);
            app.BMITab.Title = 'BMI';
            app.Label_6 = uilabel(app.BMITab);
            app.Label_6.Text = '';
            app.Label_5 = uilabel(app.BMITab);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.BMITab);
            app.Label_4.Text = '';
            app.BMILamp = uilamp(app.BMITab);
            app.ObliczButton = uibutton(app.BMITab, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.PlanujeButtonGroup = uibuttongroup(app.BMITab);
            app.PlanujeButtonGroup.Title = 'Planuje';
            app.PrzytyButton = uiradiobutton(app.PlanujeButtonGroup);
            app.PrzytyButton.Text = 'Przytyć';
            app.SchudncButton = uiradiobutton(app.PlanujeButtonGroup);
            app.SchudncButton.Text = 'Schudnąc';
            app.UtrzymamasButton = uiradiobutton(app.PlanujeButtonGroup);
            app.UtrzymamasButton.Text = 'Utrzymać masę';
            app.UtrzymamasButton.Value = true;
            app.AktywnofizycznaButtonGroup = uibuttongroup(app.BMITab);
            app.AktywnofizycznaButtonGroup.Title = 'Aktywność fizyczna';
            app.pracafizyczna5dniwtygitreningi57razyButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.pracafizyczna5dniwtygitreningi57razyButton.Text = 'praca fizyczna 5 dni w tyg i treningi 5-7 razy';
            app.niskaaktywnosiedzcytrybyciaButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.niskaaktywnosiedzcytrybyciaButton.Text = 'niska aktywność, siedzący tryb życia';
            app.niskaaktywnosiedzcytrybyciaButton.Value = true;
            app.redniaaktywnotreningi35razywtygodniuButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.redniaaktywnotreningi35razywtygodniuButton.Text = 'średnia aktywność (treningi 3-5 razy w tygodniu)';
            app.wysokaaktywnotreningi57razywtygodniuButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.wysokaaktywnotreningi57razywtygodniuButton.Text = 'wysoka aktywność (treningi 5-7 razy w tygodniu)';
            app.redniaaktywnospacerytrening2razywtygButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.redniaaktywnospacerytrening2razywtygButton.Text = 'średnia aktywność (spacery, trening 2 razy w tyg)';
            app.BMILampLabel = uilabel(app.BMITab);
            app.BMILampLabel.Text = 'BMI';
            app.PeButtonGroup = uibuttongroup(app.BMITab);
            app.PeButtonGroup.Title = 'Płeć';
            app.MczyznaButton_2 = uiradiobutton(app.PeButtonGroup);
            app.MczyznaButton_2.Text = 'Mężczyzna';
            app.KobietaButton_2 = uiradiobutton(app.PeButtonGroup);
            app.KobietaButton_2.Text = 'Kobieta';
            app.KobietaButton_2.Value = true;
            app.ObliczswojeBMIizapotrzebowaniekaloryczneLabel = uilabel(app.BMITab);
            app.ObliczswojeBMIizapotrzebowaniekaloryczneLabel.Text = 'Oblicz swoje BMI i zapotrzebowanie kaloryczne';
            app.WagakgLabel = uilabel(app.BMITab);
            app.WagakgLabel.Text = 'Waga [kg]';
            app.WagakgEditField = uieditfield(app.BMITab, 'numeric');
            app.WzrostcmEditFieldLabel = uilabel(app.BMITab);
            app.WzrostcmEditFieldLabel.Text = 'Wzrost [cm]';
            app.WzrostcmEditField = uieditfield(app.BMITab, 'numeric');
            app.WiekEditFieldLabel = uilabel(app.BMITab);
            app.WiekEditFieldLabel.Text = 'Wiek';
            app.WiekEditField = uieditfield(app.BMITab, 'numeric');
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.PrzykadowyjadospisdlaTwojegozapotrzebowaniakalorycznegoLabel = uilabel(app.MenuTab);
            app.PrzykadowyjadospisdlaTwojegozapotrzebowaniakalorycznegoLabel.Text = 'Przykładowy jadłospis, dla Twojego zapotrzebowania kalorycznego';
            app.PokamenuButton = uibutton(app.MenuTab, 'push');
            app.PokamenuButton.ButtonPushedFcn = createCallbackFcn(app, @PokamenuButtonPushed, true);
            app.PokamenuButton.Text = 'Pokaż menu';
            app.Label_7 = uilabel(app.MenuTab);
            app.Label_7.Visible = 'off';
            app.Label_8 = uilabel(app.MenuTab);
            app.Label_8.Visible = 'off';
            app.Label_9 = uilabel(app.MenuTab);
            app.Label_9.Visible = 'off';
            app.Label_10 = uilabel(app.MenuTab);
            app.Label_10.Visible = 'off';
            app.Label_11 = uilabel(app.MenuTab);
            app.Label_11.Visible = 'off';
            app.AlicjaTab = uitab(app.TabGroup);
            app.AlicjaTab.Title = 'Alicja';
            app.UITable2 = uitable(app.AlicjaTab);
            app.UITable2.ColumnName = {'Siłownia'; 'Adres'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.miastoButtonGroup = uibuttongroup(app.AlicjaTab);
            app.miastoButtonGroup.Title = 'miasto';
            app.WarszawardmiecieButton = uiradiobutton(app.miastoButtonGroup);
            app.WarszawardmiecieButton.Text = 'Warszawa Śródmieście';
            app.WarszawardmiecieButton.Value = true;
            app.WarszawaBielanyButton = uiradiobutton(app.miastoButtonGroup);
            app.WarszawaBielanyButton.Text = 'Warszawa Bielany';
            app.KrakwKazimierzButton = uiradiobutton(app.miastoButtonGroup);
            app.KrakwKazimierzButton.Text = 'Kraków Kazimierz';
            app.PokasiownieButton = uibutton(app.AlicjaTab, 'push');
            app.PokasiownieButton.ButtonPushedFcn = createCallbackFcn(app, @PokasiownieButtonPushed, true);
            app.PokasiownieButton.Text = 'Pokaż siłownie';
            app.WybierzdzielnicDropDownLabel = uilabel(app.AlicjaTab);
            app.WybierzdzielnicDropDownLabel.Text = 'Wybierz dzielnicę';
            app.WybierzdzielnicDropDown = uidropdown(app.AlicjaTab);
            app.WybierzdzielnicDropDown.Items = {'Bemowo', 'Białołęka', 'Bielany', 'Mokotów', 'Ochota', 'Praga-Południe', 'Praga-Północ', 'Rembertów', 'Śródmieście', 'Targówek', 'Ursus', 'Ursynów', 'Wawer', 'Wesoła', 'Wilanów', 'Włochy', 'Wola', 'Żoliborz'};
            app.WybierzdzielnicDropDown.Value = 'Bemowo';
            app.RankingTab = uitab(app.TabGroup);
            app.RankingTab.Title = 'Ranking';
            app.RANKINGUYTKOWNIKWLabel = uilabel(app.RankingTab);
            app.RANKINGUYTKOWNIKWLabel.Text = 'RANKING UŻYTKOWNIKÓW';
            app.PodajswjnickEditFieldLabel = uilabel(app.RankingTab);
            app.PodajswjnickEditFieldLabel.Text = 'Podaj swój nick:';
            app.PodajswjnickEditField = uieditfield(app.RankingTab, 'text');
            app.Lamp_R = uilamp(app.RankingTab);
            app.IlekilogramwudaoCisischudnLabel = uilabel(app.RankingTab);
            app.IlekilogramwudaoCisischudnLabel.Text = 'Ile kilogramów udało ci się schudnąć?';
            app.ilKg = uieditfield(app.RankingTab, 'numeric');
            app.PokawynikiButton = uibutton(app.RankingTab, 'push');
            app.PokawynikiButton.ButtonPushedFcn = createCallbackFcn(app, @PokawynikiButtonPushed, true);
            app.PokawynikiButton.Text = 'Pokaż wyniki';
            app.Label_3 = uilabel(app.RankingTab);
            app.Label_3.Text = '';
            app.UITable3 = uitable(app.RankingTab);
            app.UITable3.ColumnName = {'Nick'; 'IloscKilogramow'};
            app.UITable3.RowName = {};
            % Show the figure after all components are created
            app.FITNESSappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1mati

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FITNESSappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FITNESSappUIFigure)
        end
    end
end