classdef kamil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FITNESSappUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        WczytajButton                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        UITable                         matlab.ui.control.Table
        Button_2                        matlab.ui.control.Button
        ZapiszButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        NickEditField                   matlab.ui.control.EditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MczyznaButton                   matlab.ui.control.RadioButton
        KobietaButton                   matlab.ui.control.RadioButton
        NickEditFieldLabel              matlab.ui.control.Label
        NazwiskoEditField               matlab.ui.control.EditField
        NazwiskoEditFieldLabel          matlab.ui.control.Label
        ImiEditField                    matlab.ui.control.EditField
        ImiEditFieldLabel               matlab.ui.control.Label
        PrzedrozpoczciemzakontoLabel    matlab.ui.control.Label
        WitajLabel                      matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        Tab3                            matlab.ui.container.Tab
        AnkietaTab                      matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        jakpodobacisieaplikacjaButtonGroup  matlab.ui.container.ButtonGroup
        EditField                       matlab.ui.control.NumericEditField
        OCEAPLIKACJButton               matlab.ui.control.Button
        Button_7                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        pokawskazwkiButton              matlab.ui.control.Button
        tutajzobaczysznaszewskazwkiLabel  matlab.ui.control.Label
        wypelnijankietCheckBox          matlab.ui.control.CheckBox
        CzylubiszwiczyButtonGroup       matlab.ui.container.ButtonGroup
        nielubiButton                   matlab.ui.control.RadioButton
        bardzolubiButton                matlab.ui.control.RadioButton
        CzyczujeszsizmczonyButtonGroup  matlab.ui.container.ButtonGroup
        nieczujsizmczonyButton          matlab.ui.control.RadioButton
        jestemtrochzmczonyButton        matlab.ui.control.RadioButton
        takbardzoButton                 matlab.ui.control.RadioButton
        CzyzrobiepostpButtonGroup       matlab.ui.container.ButtonGroup
        NieButton                       matlab.ui.control.RadioButton
        TakButton                       matlab.ui.control.RadioButton
        CzywiczyszsystematycznieButtonGroup  matlab.ui.container.ButtonGroup
        NieButton_2                     matlab.ui.control.RadioButton
        TakButton_2                     matlab.ui.control.RadioButton
        IlegodzindzienniepowicasznatreningButtonGroup  matlab.ui.container.ButtonGroup
        wiecejniz3hButton               matlab.ui.control.RadioButton
        hButton_2                       matlab.ui.control.RadioButton
        hButton                         matlab.ui.control.RadioButton
        CzyjestezadowolonyztreninguButtonGroup  matlab.ui.container.ButtonGroup
        trudnopowiedzieButton           matlab.ui.control.RadioButton
        nieButton                       matlab.ui.control.RadioButton
        takButton                       matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        t %mozliwosc uzywania tabeli w innych przyciskach ale teraz wszedzie dodajemy app.t
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick'); sprintf('Płeć K/M')};
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            global t
            imie = app.ImiEditField.Value
            nazw = app.NazwiskoEditField.Value
            nick = string(app.NickEditField.Value)
            
            %petla wybierajaca plec
            if app.KobietaButton.Value ==1 %warunek wybierajacy ktory rodzaj laty 
                plec = 'K';
            elseif app.MczyznaButton.Value ==1
                plec = 'M';
            end
            nr = {imie nazw nick plec}; %new row
            i=1
            dlugosc = numel(app.t(:,3))
            
            %while i <= dlugosc
            %    if nick == app.t(i,3)
            %        app.Lamp.Color = 'r';
            %        break
            %    else 
            %        app.Lamp.Color = 'g';
            %        
            %        i=i+1 
            %        continue
            %    end
            %end
            
%ta petla stwierdza czy nick sie powtarza
            if dlugosc ==0  %tu gdy mamy pusta tabelke na poczatku
                odp = 0;
                app.Lamp.Color = 'g';
            else
                for id=1:dlugosc
                    if nick == app.t{id,3} %cell array 
                        app.Lamp.Color = 'r';
                        odp = 1
                        break
                    else
                        app.Lamp.Color = 'g';
                        odp = 0
                        continue                      
                   end
                end
            end           
%ta petla na podstawie poprzedniej decyduje czy dodajemy nowy wiersz czy nie 
            if odp == 1     %jesli odp = nie to nie robimy nic
                app.Label.Text = 'Taki Nick już istnieje.';
                app.Label_2.Text = 'Użyj innego.';
                return
            else           %w innym wypadku dodajemy to tablicy new row
                app.Label.Text = 'Wybrany przez ciebie Nick jest wolny.' ;
                app.Label_2.Text = 'Udało się!';
                app.UITable.Data = [app.t ; nr];
                app.t = app.UITable.Data;
            end
            tabela = readtable('dane.xlsx','Sheet',1);
            newtable = table()
            newtable.Imie = [imie]
            newtable.Nazwisko = [nazw]
            newtable.Nick = [nick]
            newtable.P_e_ = [plec]
            tabela = [tabela; newtable]
            writetable(tabela,'dane.xlsx')
        
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            app.t = readtable('dane.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick')};
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Value changed function: wypelnijankietCheckBox
        function wypelnijankietCheckBoxValueChanged(app, event)
            value = app.wypelnijankietCheckBox.Value;
            switch value
                case 0
                    app.CzyjestezadowolonyztreninguButtonGroup.Visible = 'off'
                    app.CzyzrobiepostpButtonGroup.Visible='off'
                    app.CzywiczyszsystematycznieButtonGroup.Visible='off'
                    app.IlegodzindzienniepowicasznatreningButtonGroup.Visible='off'
                    app.CzyczujeszsizmczonyButtonGroup.Visible='off'
                    app.CzylubiszwiczyButtonGroup.Visible='off'
                case 1
                    app.CzyjestezadowolonyztreninguButtonGroup.Visible = 'on'
                    app.CzyzrobiepostpButtonGroup.Visible='on'
                    app.CzywiczyszsystematycznieButtonGroup.Visible='on'
                    app.IlegodzindzienniepowicasznatreningButtonGroup.Visible='on'
                    app.CzyczujeszsizmczonyButtonGroup.Visible='on'
                    app.CzylubiszwiczyButtonGroup.Visible='on'
                end
            
        end

        % Button pushed function: pokawskazwkiButton
        function pokawskazwkiButtonPushed(app, event)
            if app.TakButton.Value==1 && app.bardzolubiButton.Value==1 && app.nieczujsizmczonyButton.Value==1 && app.TakButton_2.Value==1 && app.takButton.Value==1 && app.wiecejniz3hButton.Value==1
                app.tutajzobaczysznaszewskazwkiLabel.Text = "Doskonale!!! Jesteś perfekcjonistą. Trening sprawia ci przyjemność. Utrzymuj dotychczasowe zaangażowanie w trening"
            elseif app.NieButton.Value==1 && app.bardzolubiButton.Value==1 && app.takbardzoButton.Value==1 && app.TakButton_2.Value==1 && app.takButton.Value==1 && app.hButton_2.Value==1
                app.tutajzobaczysznaszewskazwkiLabel.Text = "Bardzo dobrze!!! Widać że trening sprawia ci dużo radości. Jesteś bardzo pracowity i systematyczny. Starasz sie dążyć do określonych przez siebie celów. Dzieki tym cechom szybko osiągniesz to co sobie postanowiłeś. Dalej pracuj ciężko a osiągniesz najlepszą forme. Powodzenia!!!"
            elseif app.NieButton.Value==1 && app.nielubiButton.Value==1 && app.takbardzoButton.Value==1 && app.NieButton_2.Value==1 && app.nieButton.Value==1 && app.hButton.Value==1
                app.tutajzobaczysznaszewskazwkiLabel.Text = "Niestety przed Tobą jeszcze bardzo dużo pracy!!! Musisz być bardziej systematyczny i więcej czasu poświęcać na treningi to pomoże Ci poprawić kondycję. Dzieki ćwiczeniom będziesz widzał postepy a to sprawi że treningi będą przyjemnością a nie obowiązkiem"
            elseif app.TakButton.Value==1 && app.bardzolubiButton.Value==1 && app.jestemtrochzmczonyButton.Value==1 && app.TakButton_2.Value==1 && app.takButton.Value==1 && app.hButton_2.Value==1
                app.tutajzobaczysznaszewskazwkiLabel.Text = "Bardzo dobrze że ćwiczenia sprawiają ci radość. Musisz jeszcze trochę popracować nad swoją kondycją a pomoże ci w tym na pewno twoja systematyczność. Najważniejsze że widzisz postepy bo to sprawia że jesteś zmotywowany do dalszej pracy "
            end
        end

        % Button pushed function: OCEAPLIKACJButton
        function OCEAPLIKACJButtonPushed(app, event)
            value = app.EditField.Value;
            
            a = 1;
            b = 2;
            c = 3;
            d = 4;
            e = 5;
            
            lista = [];
            if app.Button_3.Value==1  
                lista.append(a)
            elseif app.Button_4.Value==1  
                lista.append(b)
            elseif app.Button_5.Value==1  
                lista.append(c)
            elseif app.Button_6.Value==1  
                lista.append(d)
            elseif app.Button_7.Value==1  
                lista.append(e)
            end
            srednia = sum(lista)/length(lista)
            app.EditField.Value= srednia
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FITNESSappUIFigure = uifigure('Visible', 'off');
            app.FITNESSappUIFigure.Name = 'FITNESSapp';
            app.TabGroup = uitabgroup(app.FITNESSappUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.WitajLabel = uilabel(app.Tab);
            app.WitajLabel.Text = 'Witaj!  ';
            app.PrzedrozpoczciemzakontoLabel = uilabel(app.Tab);
            app.PrzedrozpoczciemzakontoLabel.Text = 'Przed rozpoczęciem - załóż konto:';
            app.Panel = uipanel(app.Tab);
            app.ImiEditFieldLabel = uilabel(app.Panel);
            app.ImiEditFieldLabel.Text = 'Imię';
            app.ImiEditField = uieditfield(app.Panel, 'text');
            app.NazwiskoEditFieldLabel = uilabel(app.Panel);
            app.NazwiskoEditFieldLabel.Text = 'Nazwisko';
            app.NazwiskoEditField = uieditfield(app.Panel, 'text');
            app.NickEditFieldLabel = uilabel(app.Panel);
            app.NickEditFieldLabel.Text = 'Nick';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.KobietaButton = uiradiobutton(app.ButtonGroup);
            app.KobietaButton.Text = 'Kobieta';
            app.KobietaButton.Value = true;
            app.MczyznaButton = uiradiobutton(app.ButtonGroup);
            app.MczyznaButton.Text = 'Mężczyzna';
            app.NickEditField = uieditfield(app.Panel, 'text');
            app.ZapiszButton = uibutton(app.Tab, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Icon = 'zapisz.png';
            app.ZapiszButton.Text = 'Zapisz';
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.Icon = 'LOGO.jpg';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.Tab);
            app.Button = uibutton(app.Tab, 'push');
            app.Button.Icon = 'LOGO.jpg';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '';
            app.WczytajButton = uibutton(app.Tab, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Visible = 'off';
            app.WczytajButton.Text = 'Wczytaj';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.AnkietaTab = uitab(app.TabGroup);
            app.AnkietaTab.Title = 'Ankieta';
            app.CzyjestezadowolonyztreninguButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzyjestezadowolonyztreninguButtonGroup.Title = 'Czy jesteś zadowolony z treningu?';
            app.takButton = uiradiobutton(app.CzyjestezadowolonyztreninguButtonGroup);
            app.takButton.Text = 'tak';
            app.takButton.Value = true;
            app.nieButton = uiradiobutton(app.CzyjestezadowolonyztreninguButtonGroup);
            app.nieButton.Text = 'nie';
            app.trudnopowiedzieButton = uiradiobutton(app.CzyjestezadowolonyztreninguButtonGroup);
            app.trudnopowiedzieButton.Text = 'trudno powiedzieć';
            app.IlegodzindzienniepowicasznatreningButtonGroup = uibuttongroup(app.AnkietaTab);
            app.IlegodzindzienniepowicasznatreningButtonGroup.Title = 'Ile godzin dziennie poświęcasz na trening?';
            app.hButton = uiradiobutton(app.IlegodzindzienniepowicasznatreningButtonGroup);
            app.hButton.Text = '1h';
            app.hButton.Value = true;
            app.hButton_2 = uiradiobutton(app.IlegodzindzienniepowicasznatreningButtonGroup);
            app.hButton_2.Text = '2-3h';
            app.wiecejniz3hButton = uiradiobutton(app.IlegodzindzienniepowicasznatreningButtonGroup);
            app.wiecejniz3hButton.Text = 'wiecej niz 3h';
            app.CzywiczyszsystematycznieButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzywiczyszsystematycznieButtonGroup.Title = 'Czy ćwiczysz systematycznie?';
            app.TakButton_2 = uiradiobutton(app.CzywiczyszsystematycznieButtonGroup);
            app.TakButton_2.Text = 'Tak';
            app.TakButton_2.Value = true;
            app.NieButton_2 = uiradiobutton(app.CzywiczyszsystematycznieButtonGroup);
            app.NieButton_2.Text = 'Nie';
            app.CzyzrobiepostpButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzyzrobiepostpButtonGroup.Title = 'Czy zrobiłeś postęp?';
            app.TakButton = uiradiobutton(app.CzyzrobiepostpButtonGroup);
            app.TakButton.Text = 'Tak';
            app.TakButton.Value = true;
            app.NieButton = uiradiobutton(app.CzyzrobiepostpButtonGroup);
            app.NieButton.Text = 'Nie';
            app.CzyczujeszsizmczonyButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzyczujeszsizmczonyButtonGroup.Title = 'Czy czujesz się zmęczony?';
            app.takbardzoButton = uiradiobutton(app.CzyczujeszsizmczonyButtonGroup);
            app.takbardzoButton.Text = 'tak bardzo';
            app.takbardzoButton.Value = true;
            app.jestemtrochzmczonyButton = uiradiobutton(app.CzyczujeszsizmczonyButtonGroup);
            app.jestemtrochzmczonyButton.Text = 'jestem trochę zmęczony';
            app.nieczujsizmczonyButton = uiradiobutton(app.CzyczujeszsizmczonyButtonGroup);
            app.nieczujsizmczonyButton.Text = 'nie czuję się zmęczony';
            app.CzylubiszwiczyButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzylubiszwiczyButtonGroup.Title = 'Czy lubisz ćwiczyć?';
            app.bardzolubiButton = uiradiobutton(app.CzylubiszwiczyButtonGroup);
            app.bardzolubiButton.Text = 'bardzo lubię';
            app.bardzolubiButton.Value = true;
            app.nielubiButton = uiradiobutton(app.CzylubiszwiczyButtonGroup);
            app.nielubiButton.Text = {'nie lubię'; ''};
            app.wypelnijankietCheckBox = uicheckbox(app.AnkietaTab);
            app.wypelnijankietCheckBox.ValueChangedFcn = createCallbackFcn(app, @wypelnijankietCheckBoxValueChanged, true);
            app.wypelnijankietCheckBox.Text = 'wypelnij ankietę';
            app.wypelnijankietCheckBox.Value = true;
            app.tutajzobaczysznaszewskazwkiLabel = uilabel(app.AnkietaTab);
            app.tutajzobaczysznaszewskazwkiLabel.Text = 'tutaj zobaczysz nasze wskazówki';
            app.pokawskazwkiButton = uibutton(app.AnkietaTab, 'push');
            app.pokawskazwkiButton.ButtonPushedFcn = createCallbackFcn(app, @pokawskazwkiButtonPushed, true);
            app.pokawskazwkiButton.Text = 'pokaż wskazówki';
            app.jakpodobacisieaplikacjaButtonGroup = uibuttongroup(app.AnkietaTab);
            app.jakpodobacisieaplikacjaButtonGroup.Title = 'jak podoba ci sie aplikacja?';
            app.Button_3 = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.Button_3.Text = '1';
            app.Button_3.Value = true;
            app.Button_4 = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.Button_4.Text = '2';
            app.Button_5 = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.Button_5.Text = '3';
            app.Button_6 = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.Button_6.Text = '4';
            app.Button_7 = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.Button_7.Text = '5';
            app.OCEAPLIKACJButton = uibutton(app.jakpodobacisieaplikacjaButtonGroup, 'push');
            app.OCEAPLIKACJButton.ButtonPushedFcn = createCallbackFcn(app, @OCEAPLIKACJButtonPushed, true);
            app.OCEAPLIKACJButton.Text = 'OCEŃ APLIKACJĘ';
            app.EditField = uieditfield(app.jakpodobacisieaplikacjaButtonGroup, 'numeric');
            app.Image = uiimage(app.AnkietaTab);
            % Show the figure after all components are created
            app.FITNESSappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kamil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FITNESSappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FITNESSappUIFigure)
        end
    end
end