classdef kopia_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ListafilmwListBox         matlab.ui.control.ListBox
        ListafilmwListBoxLabel    matlab.ui.control.Label
        RamionaEditField          matlab.ui.control.EditField
        RamionaEditFieldLabel     matlab.ui.control.Label
        PlecyEditField            matlab.ui.control.EditField
        PlecyEditFieldLabel       matlab.ui.control.Label
        ABSEditField              matlab.ui.control.EditField
        ABSEditFieldLabel         matlab.ui.control.Label
        KoniecEditField           matlab.ui.control.EditField
        KoniecEditFieldLabel      matlab.ui.control.Label
        StartEditField            matlab.ui.control.EditField
        StartEditFieldLabel       matlab.ui.control.Label
        DodajButton               matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        NazwafilmuEditField       matlab.ui.control.EditField
        NazwafilmuEditFieldLabel  matlab.ui.control.Label
        CzasButton                matlab.ui.control.Button
        WczButton                 matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Nazwafilmu % Description
        TempFile = 'TempFile.avi'; % Description
    end
    
    methods (Access = private)
        
        function Wcz(app)
            v = VideoReader(app.Nazwafilmu);
            
            app.Slider.Limits = [0, v.Duration];
            app.Slider.MajorTickLabels = {num2str(0), num2str(v.Duration/2), num2str(v.Duration)};
            app.Slider.MajorTicks = [0, v.Duration/2, v.Duration];
        
            while hasFrame(v)
                Frame = readFrame(v);
                imshow(Frame, 'Parent', app.UIAxes)
                if(v.CurrentTime < v.Duration)
                    app.Slider.Value = v.CurrentTime;
                end
                
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczButton
        function WczButtonPushed(app, event)
            app.Nazwafilmu = app.NazwafilmuEditField.Value
            Wcz(app);
        end

        % Button pushed function: CzasButton
        function CzasButtonPushed(app, event)
            v = VideoReader(app.NazwafilmuEditField.Value);
            w = VideoReader(app.TempFile);
            open(w);
            
            while hasFrame(v)
                Frame = readFrame(v);
                
                if(v.CurrentTime < app.StartEditField.Value || ...
                        v.CurrentTime > app.KoniecEditField.Value) 
                    
                    writeVideo(w, Frame);
                end
                
                
            end
            
            close(w);
            app.Nazwafilmu = app.TempFile;
            Wcz(app);
        end

        % Button pushed function: DodajButton
        function DodajButtonPushed(app, event)
            v1 = app.ABSEditField.Value;
            v2 = app.PlecyEditField.Value;
            v3 = app.RamionaEditField.Value;
            
            if ~(isfile(v1) && isfile(v2) && isfile(v3))
                msgbox('ABS and Plecy and Ramiona must be defined', "Error");
                return;
            end
            
            v1_VR = VideoReader(v1);
            v2_VR = VideoReader(v2);
            v3_VR = VideoReader(v3);
            
            w = VideoWriter(app.TempFile);
            open(w)
            
            while hasFrame(v1_VR)
                Frame = readFrame(v1_VR);
                writeVideo(w, Frame);
            end
            
            while hasFrame(v2_VR)
                Frame = readFrame(v2_VR);
                writeVideo(w, Frame);
            end
            
            while hasFrame(v3_VR)
                Frame = readFrame(v3_VR);
                writeVideo(w, Frame);
            end
            
            close(w);
            app.Nazwafilmu = app.TempFile;
            Wcz(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.WczButton = uibutton(app.UIFigure, 'push');
            app.WczButton.ButtonPushedFcn = createCallbackFcn(app, @WczButtonPushed, true);
            app.WczButton.Text = 'Włącz';
            app.CzasButton = uibutton(app.UIFigure, 'push');
            app.CzasButton.ButtonPushedFcn = createCallbackFcn(app, @CzasButtonPushed, true);
            app.CzasButton.Text = 'Czas';
            app.NazwafilmuEditFieldLabel = uilabel(app.UIFigure);
            app.NazwafilmuEditFieldLabel.Text = 'Nazwa filmu';
            app.NazwafilmuEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.5 1];
            app.Slider.MinorTicks = [];
            app.DodajButton = uibutton(app.UIFigure, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @DodajButtonPushed, true);
            app.DodajButton.Text = 'Dodaj';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'text');
            app.KoniecEditFieldLabel = uilabel(app.UIFigure);
            app.KoniecEditFieldLabel.Text = 'Koniec';
            app.KoniecEditField = uieditfield(app.UIFigure, 'text');
            app.ABSEditFieldLabel = uilabel(app.UIFigure);
            app.ABSEditFieldLabel.Text = 'ABS';
            app.ABSEditField = uieditfield(app.UIFigure, 'text');
            app.PlecyEditFieldLabel = uilabel(app.UIFigure);
            app.PlecyEditFieldLabel.Text = 'Plecy';
            app.PlecyEditField = uieditfield(app.UIFigure, 'text');
            app.RamionaEditFieldLabel = uilabel(app.UIFigure);
            app.RamionaEditFieldLabel.Text = 'Ramiona';
            app.RamionaEditField = uieditfield(app.UIFigure, 'text');
            app.ListafilmwListBoxLabel = uilabel(app.UIFigure);
            app.ListafilmwListBoxLabel.Text = 'Lista filmów';
            app.ListafilmwListBox = uilistbox(app.UIFigure);
            app.ListafilmwListBox.Items = {'brzuch.mp4', 'zdrowykregoslup.mp4', 'ramiona.mp4', ''};
            app.ListafilmwListBox.Value = 'brzuch.mp4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kopia_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end