classdef ostateczna < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FITNESSappUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LogowanieTab                    matlab.ui.container.Tab
        WitajLabel                      matlab.ui.control.Label
        PrzedrozpoczciemzakontoLabel    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ImiEditFieldLabel               matlab.ui.control.Label
        ImiEditField                    matlab.ui.control.EditField
        NazwiskoEditFieldLabel          matlab.ui.control.Label
        NazwiskoEditField               matlab.ui.control.EditField
        NickEditFieldLabel              matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        KobietaButton                   matlab.ui.control.RadioButton
        MczyznaButton                   matlab.ui.control.RadioButton
        NickEditField                   matlab.ui.control.EditField
        ZapiszButton                    matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Lamp                            matlab.ui.control.Lamp
        Button                          matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        WczytajButton                   matlab.ui.control.Button
        BMITab                          matlab.ui.container.Tab
        WagakgEditFieldLabel            matlab.ui.control.Label
        WzrostcmEditFieldLabel          matlab.ui.control.Label
        WiekEditFieldLabel              matlab.ui.control.Label
        BMILampLabel                    matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        BMILamp                         matlab.ui.control.Lamp
        ObliczButton                    matlab.ui.control.Button
        PlanujeButtonGroup              matlab.ui.container.ButtonGroup
        PrzytyButton                    matlab.ui.control.RadioButton
        SchudnButton                    matlab.ui.control.RadioButton
        UtrzymamasButton                matlab.ui.control.RadioButton
        WiekEditField                   matlab.ui.control.NumericEditField
        WzrostcmEditField               matlab.ui.control.NumericEditField
        WagakgEditField                 matlab.ui.control.NumericEditField
        AktywnofizycznaButtonGroup      matlab.ui.container.ButtonGroup
        pracafizyczna5dniwtygitreningi57razyButton  matlab.ui.control.RadioButton
        wysokaaktywnotreningi57razywtygodniuButton  matlab.ui.control.RadioButton
        redniaaktywnotreningi35razywtygodniuButton  matlab.ui.control.RadioButton
        redniaaktywnospacerytrening2razywtygButton  matlab.ui.control.RadioButton
        niskaaktywnosiedzcytrybyciaButton  matlab.ui.control.RadioButton
        PeButtonGroup                   matlab.ui.container.ButtonGroup
        KobietaButton_2                 matlab.ui.control.RadioButton
        MczyznaButton_2                 matlab.ui.control.RadioButton
        ObliczswojeBMIizapotrzebowaniekaloryczneLabel  matlab.ui.control.Label
        MenuTab                         matlab.ui.container.Tab
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        PrzykadowyjadospisdlaTwojegozapotrzebowaniakalorycznegoLabel  matlab.ui.control.Label
        Image13                         matlab.ui.control.Image
        SiownieTab                      matlab.ui.container.Tab
        DzielnicaButtonGroup            matlab.ui.container.ButtonGroup
        BemowoButton                    matlab.ui.control.RadioButton
        BiaokaButton                    matlab.ui.control.RadioButton
        BielanyButton                   matlab.ui.control.RadioButton
        MokotwButton                    matlab.ui.control.RadioButton
        OchotaButton                    matlab.ui.control.RadioButton
        PragaPoudnieButton              matlab.ui.control.RadioButton
        PragaPnocButton                 matlab.ui.control.RadioButton
        RembertwButton                  matlab.ui.control.RadioButton
        rdmiecieButton                  matlab.ui.control.RadioButton
        TargwekButton                   matlab.ui.control.RadioButton
        UrsusButton                     matlab.ui.control.RadioButton
        UrsynwButton                    matlab.ui.control.RadioButton
        WawerButton                     matlab.ui.control.RadioButton
        WesoaButton                     matlab.ui.control.RadioButton
        WilanwButton                    matlab.ui.control.RadioButton
        WochyButton                     matlab.ui.control.RadioButton
        WolaButton                      matlab.ui.control.RadioButton
        oliborzButton                   matlab.ui.control.RadioButton
        PokasiownieButton               matlab.ui.control.Button
        UITable4                        matlab.ui.control.Table
        Image3                          matlab.ui.control.Image
        SiowniewWarszawieLabel          matlab.ui.control.Label
        SzlakirowerowewersjamiejskaTab  matlab.ui.container.Tab
        ZnajdszlakButton                matlab.ui.control.Button
        RodzajtrasyButtonGroup          matlab.ui.container.ButtonGroup
        krtkaok5kmButton                matlab.ui.control.RadioButton
        dugapowyej20kmButton            matlab.ui.control.RadioButton
        DzielniceWarszawyButtonGroup    matlab.ui.container.ButtonGroup
        rdmiecieButton_2                matlab.ui.control.RadioButton
        OchotaButton_2                  matlab.ui.control.RadioButton
        WolaButton_2                    matlab.ui.control.RadioButton
        MokotwButton_2                  matlab.ui.control.RadioButton
        PragaPoudnieButton_2            matlab.ui.control.RadioButton
        PragaPnocButton_2               matlab.ui.control.RadioButton
        Image5                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        SzlakirowerowewersjawakacyjnaTab  matlab.ui.container.Tab
        ZnajdszlakrowerowyButton        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RodzajtrasyButtonGroup_2        matlab.ui.container.ButtonGroup
        dlapocztkujcychButton           matlab.ui.control.RadioButton
        dlazaawansowanychButton         matlab.ui.control.RadioButton
        MiejscawPolsceButtonGroup       matlab.ui.container.ButtonGroup
        dolinaBiebrzyiNarwiiButton      matlab.ui.control.RadioButton
        GrywitokrzyskieiokoliceButton   matlab.ui.control.RadioButton
        NadZalewemWilanymButton         matlab.ui.control.RadioButton
        PogrzeKarpackieButton           matlab.ui.control.RadioButton
        PolesieButton                   matlab.ui.control.RadioButton
        PnocneMazuryButton              matlab.ui.control.RadioButton
        PuszczaBiaowieskaButton         matlab.ui.control.RadioButton
        RoztoczeButton                  matlab.ui.control.RadioButton
        SuwalszczyznaButton             matlab.ui.control.RadioButton
        WarmiaiokoliceButton            matlab.ui.control.RadioButton
        EditField                       matlab.ui.control.EditField
        Image2                          matlab.ui.control.Image
        wiczeniaTab                     matlab.ui.container.Tab
        WybierzpartiktraCiinteresujeButtonGroup  matlab.ui.container.ButtonGroup
        ABSButton                       matlab.ui.control.RadioButton
        RamionaButton                   matlab.ui.control.RadioButton
        UdaButton                       matlab.ui.control.RadioButton
        PoladkiButton                   matlab.ui.control.RadioButton
        CaeciaoButton                   matlab.ui.control.RadioButton
        TABATAButton                    matlab.ui.control.RadioButton
        RozgrzewkaButton                matlab.ui.control.RadioButton
        RozciganiepotreninguButton      matlab.ui.control.RadioButton
        WczfilmButton                   matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Image14                         matlab.ui.control.Image
        CiekawostkiTab                  matlab.ui.container.Tab
        WylosujciekawostkButton         matlab.ui.control.Button
        TutajwywietlisiTwojaciekawostkaLabel  matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        AnkietaTab                      matlab.ui.container.Tab
        CzyjestezadowolonyztreninguButtonGroup  matlab.ui.container.ButtonGroup
        takButton                       matlab.ui.control.RadioButton
        nieButton                       matlab.ui.control.RadioButton
        trudnopowiedzieButton           matlab.ui.control.RadioButton
        IlegodzindziennietrenujeszButtonGroup  matlab.ui.container.ButtonGroup
        hButton                         matlab.ui.control.RadioButton
        hButton_2                       matlab.ui.control.RadioButton
        wiecejniz3hButton               matlab.ui.control.RadioButton
        CzywiczyszsystematycznieButtonGroup  matlab.ui.container.ButtonGroup
        TakButton                       matlab.ui.control.RadioButton
        NieButton                       matlab.ui.control.RadioButton
        CzyzrobiepostpButtonGroup       matlab.ui.container.ButtonGroup
        TakButton_2                     matlab.ui.control.RadioButton
        NieButton_2                     matlab.ui.control.RadioButton
        CzyczujeszsizmczonyButtonGroup  matlab.ui.container.ButtonGroup
        takbardzoButton                 matlab.ui.control.RadioButton
        jestemtrochzmczonyButton        matlab.ui.control.RadioButton
        nieczujsizmczonyButton          matlab.ui.control.RadioButton
        CzylubiszwiczyButtonGroup       matlab.ui.container.ButtonGroup
        bardzolubiButton                matlab.ui.control.RadioButton
        nielubiButton                   matlab.ui.control.RadioButton
        pokawskazwkiButton              matlab.ui.control.Button
        jakpodobacisieaplikacjaButtonGroup  matlab.ui.container.ButtonGroup
        jestsuperButton                 matlab.ui.control.RadioButton
        bardzopomocnaButton             matlab.ui.control.RadioButton
        jestemrozczarowanyaButton       matlab.ui.control.RadioButton
        OCEAPLIKACJButton               matlab.ui.control.Button
        CzeLabel                        matlab.ui.control.Label
        TutajzobaczysznaszewskazwkiLabel  matlab.ui.control.Label
        wypelnijankietCheckBox          matlab.ui.control.CheckBox
        RankingTab                      matlab.ui.container.Tab
        Image7                          matlab.ui.control.Image
        ilKg                            matlab.ui.control.NumericEditField
        RANKINGUYTKOWNIKWLabel          matlab.ui.control.Label
        PodajswjnickEditFieldLabel      matlab.ui.control.Label
        PodajswjnickEditField           matlab.ui.control.EditField
        Lamp_R                          matlab.ui.control.Lamp
        IlekilogramwudaoCisischudnLabel  matlab.ui.control.Label
        PokawynikiButton                matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
    end

    
    properties (Access = private)
        t %mozliwosc uzywania tabeli w innych przyciskach ale teraz wszedzie dodajemy app.t
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = readtable('dane3.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick'); sprintf('Płeć K/M')};
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            global t
            imie = app.ImiEditField.Value
            nazw = app.NazwiskoEditField.Value
            nick = string(app.NickEditField.Value)
            
            %petla wybierajaca plec
            if app.KobietaButton.Value ==1 %warunek wybierajacy ktory rodzaj laty 
                plec = 'K';
            elseif app.MczyznaButton.Value ==1
                plec = 'M';
            end
            nr = {imie nazw nick plec}; %new row
            i=1
            dlugosc = numel(app.t(:,3))
            
            %while i <= dlugosc
            %    if nick == app.t(i,3)
            %        app.Lamp.Color = 'r';
            %        break
            %    else 
            %        app.Lamp.Color = 'g';
            %        
            %        i=i+1 
            %        continue
            %    end
            %end
            
%ta petla stwierdza czy nick sie powtarza
            if dlugosc ==0  %tu gdy mamy pusta tabelke na poczatku
                odp = 0;
                app.Lamp.Color = 'g';
            else
                for id=1:dlugosc
                    if nick == app.t{id,3} %cell array 
                        app.Lamp.Color = 'r';
                        odp = 1
                        break
                    else
                        app.Lamp.Color = 'g';
                        odp = 0
                        continue                      
                   end
                end
            end           
%ta petla na podstawie poprzedniej decyduje czy dodajemy nowy wiersz czy nie 
            if odp == 1     %jesli odp = nie to nie robimy nic
                app.Label.Text = 'Taki Nick już istnieje.';
                app.Label_2.Text = 'Użyj innego.';
                return
            else           %w innym wypadku dodajemy to tablicy new row
                app.Label.Text = 'Wybrany przez ciebie Nick jest wolny.' ;
                app.Label_2.Text = 'Udało się!';
                app.UITable.Data = [app.t ; nr];
                app.t = app.UITable.Data;
            end
            tabela = readtable('dane3.xlsx','Sheet',1);
            newtable = table()
            newtable.Imie = [imie]
            newtable.Nazwisko = [nazw]
            newtable.Nick = [nick]
            newtable.P_e_ = [plec]
            tabela = [tabela; newtable]
            writetable(tabela,'dane3.xlsx')
       
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            app.t = readtable('dane3.xlsx','Sheet',1);
            app.UITable.Data = app.t;
            app.UITable.ColumnName = {sprintf('Imię'); sprintf('Nazwisko'); sprintf('Nick')};
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: PokasiownieButton
        function PokasiownieButtonPushed(app, event)
            
            tabka=readtable('silownie.xlsx');
            dlug = (tabka.X);
            szer=(tabka.Y);
            adr=tabka.adres;
            naz=tabka.nazwa;
            
%             silownia1= [52.25723, 20.98457351, "ZDROFIT", "al. Jana Pawła II 82, CH ARKADIA"]  ;
%             silownia2= [52.232879, 20.999556, "ZDROFIT", "ul. RONDO ONZ 1"] ;
%             silownia3= [52.2941895, 20.9303168, "ZDROFIT", "ul. Zgrupowania AK 'Kampinos' 15, GALERIA MŁOCINY"] ;
%             silownia4= [52.2912362, 20.9363057, "ZDROFIT" "ul. Przy Agorze 22"];
%             silownia5=["Siłownia Kulturystyczna CHAMPION", "ul. Księdza Augustyna Kordeckiego 7"];
%             silownia6=["Atlantic Squash & Fitness","ul. Stradomska 15"];
%             silownia7=["fitLady", "ul. Marii Konopnickiej 5"];
%             silownia8=["EMS 2020", "ul. Antoniego Józefa Madalińskiego 11/3"];
%             silownia9=["ZDROFIT", "al. Grunwaldzka 82, CH MANHATTAN"];
%             silownia10=["Tonezone", "ul. Jana Kilińskiego 4"];
%             silownia11=["CityFit", "Targ Sienny 7"];
%             silownia12=["Yoga Compass", "ul. Wojciecha Bogusławskiego 1"];
            
            
%             WarszawaBielany=[silownia3(3), silownia3(4); silownia4(3), silownia4(4)];
%             WarszawaSrodmiescie=[silownia1(3), silownia1(4); silownia2(3), silownia2(4)];
%             KrakowKazimierz=[silownia5(1), silownia5(2); silownia6(1), silownia6(2)];
%             KrakowDebniki=[silownia7(1), silownia7(2); silownia8(1), silownia8(2)];
%             GdanskWrzeszcz=[silownia9(1), silownia9(2); silownia10(1), silownia10(2)];
%             GdanskSrodmiescie=[silownia11(1), silownia11(2); silownia12(1), silownia12(2)];
%             app.UITable2.Data=tabela;
%             app.UITable2.Visible='on';
            % app.DzielnicaButtonGroup.Scrollable='on';   

            
           if app.BemowoButton.Value == 1
               tabka=table(naz(10:18), adr(10:18));
               geoscatter([dlug(10:18)], [szer(10:18)],'^')
               geobasemap('streets')
           elseif app.BiaokaButton.Value == 1
               tabka=table(naz(19:27), adr(19:27));
               geoscatter([dlug(19:27)], [szer(19:27)],'^')
               geobasemap('streets')
           elseif app.BielanyButton.Value == 1
               tabka=table(naz(1:9), adr(1:9));
               geoscatter([dlug(1:9)], [szer(1:9)],'^')
                geobasemap('streets')
           elseif app.MokotwButton.Value == 1
               tabka=table(naz(28:36), adr(28:36));
               geoscatter([dlug(28:36)], [szer(28:36)],'^')
                geobasemap('streets')
           elseif app.OchotaButton.Value == 1
               tabka=table(naz(37:45), adr(37:45));
               geoscatter([dlug(37:45)], [szer(37:45)],'^')
                geobasemap('streets')
           elseif app.PragaPoudnieButton.Value == 1
               tabka=table(naz(46:54), adr(46:54));
               geoscatter([dlug(46:54)], [szer(46:54)],'^')
                geobasemap('streets')
           elseif app.PragaPnocButton.Value == 1
               tabka=table(naz(55:63), adr(55:63));
               geoscatter([dlug(55:63)], [szer(55:63)],'^') 
                geobasemap('streets')
           elseif app.RembertwButton.Value == 1
               tabka=table(naz(64:68), adr(64:68));
               geoscatter([dlug(64:68)], [szer(64:68)],'^')
                geobasemap('streets')
           elseif app.rdmiecieButton.Value == 1
               tabka=table(naz(69:77), adr(69:77));
               geoscatter([dlug(69:77)], [szer(69:77)],'^') 
                geobasemap('streets')
           elseif app.TargwekButton.Value == 1
               tabka=table(naz(78:86), adr(78:86));
               geoscatter([dlug(78:86)], [szer(78:86)],'^')
                geobasemap('streets')
           elseif app.UrsusButton.Value == 1
               tabka=table(naz(87:95), adr(87:95));
               geoscatter([dlug(87:95)], [szer(87:95)],'^')
                geobasemap('streets')
           elseif app.UrsynwButton.Value == 1
               tabka=table(naz(96:104), adr(96:104));
               geoscatter([dlug(96:104)], [szer(96:104)],'^')
                geobasemap('streets')
           elseif app.WawerButton.Value == 1
               tabka=table(naz(105:112), adr(105:112));
               geoscatter([dlug(105:112)], [szer(105:112)],'^')
                geobasemap('streets')
           elseif app.WesoaButton.Value == 1
               tabka=table(naz(113:121), adr(113:121));
               geoscatter([dlug(113:121)], [szer(113:121)],'^')
                geobasemap('streets')
           elseif app.WilanwButton.Value == 1
               tabka=table(naz(122:130), adr(122:130));
               geoscatter([dlug(122:130)], [szer(122:130)],'^')
                geobasemap('streets')
           elseif app.WochyButton.Value == 1
               tabka=table(naz(131:139), adr(131:139));
               geoscatter([dlug(131:139)], [szer(131:139)],'^')
                geobasemap('streets')
           elseif app.WolaButton.Value == 1
               tabka=table(naz(140:148), adr(140:148));
               geoscatter([dlug(140:148)], [szer(140:148)],'^')
                geobasemap('streets')
           elseif app.oliborzButton.Value == 1
               tabka=table(naz(149:157), adr(149:157));
               geoscatter([dlug(149:157)], [szer(149:157)],'^')
                geobasemap('streets')
            
           end


           app.UITable4.Data=tabka;
           app.UITable4.Visible='on';
%            app.DzielnicaButtonGroup.Scrollable='on';
            
        end

        % Button pushed function: PokawynikiButton
        function PokawynikiButtonPushed(app, event)
            global t
            nick1 = string(app.PodajswjnickEditField.Value)
            ilKg = app.ilKg.Value
            dlugosc = numel(app.t(:,3))
            
            for id=1:dlugosc
                    if nick1 == app.t{id,3} 
                       app.Lamp_R.Color = 'g';
                       warunek_T_N = 0
                       break
                    else
                       app.Lamp_R.Color = 'r';
                       warunek_T_N = 1
                       continue                      
                   end
            end           
            if warunek_T_N == 0
                tabelaR = readtable('ranking3.xlsx','Sheet',1);
                newtableR = table()
                newtableR.Nick = [nick1]
                newtableR.IloscKilogramow = [ilKg]
                tabelaR = [tabelaR; newtableR] 
                tabelasort = sortrows(tabelaR,{'IloscKilogramow'},{'descend'})
                app.UITable3.Data=tabelasort;
                app.UITable3.Visible='on';
                writetable(tabelaR,'ranking3.xlsx')
                app.Label_3.Text = ['Jest takie konto! ' ...
                    'Zobacz na którym miejscu jestes.'];
            else 
                app.Label_3.Text = ['Nie ma takiego użytkownika. ' ...
                    'Załóż konto.'];
            end
            

        end

        % Button pushed function: ZnajdszlakButton
        function ZnajdszlakButtonPushed(app, event)
           if app.MokotwButton_2.Value == 1
              if app.krtkaok5kmButton.Value == 1
            
            tracks = gpxread('morysin-dawne-wlosci-potockich.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dugapowyej20kmButton.Value == 1
                   tracks = gpxread('wokol-wisly-mokotow-most-siekierkowski-prom-karczew-gassy-siekierki-mokotow.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
                  
              end
           end
           
           if app.rdmiecieButton_2.Value == 1
              if app.krtkaok5kmButton.Value == 1
            
            tracks = gpxread('warszawa-srodmiescie-powazki.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dugapowyej20kmButton.Value == 1
                   tracks = gpxread('wokol-wisly-ii-most-poniatowskiego-wybrz-gdanskie-most-m-s-curie-sciezka-m-poniatowskiego.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
                  
              end
           end
           
            if app.WolaButton_2.Value == 1
              if app.krtkaok5kmButton.Value == 1
            
            tracks = gpxread('w-wa-1.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dugapowyej20kmButton.Value == 1
                   tracks = gpxread('losiowe-blota-3.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
                  
              end
            end
            
             if app.OchotaButton_2.Value == 1
              if app.krtkaok5kmButton.Value == 1
            
            tracks = gpxread('asd-6.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dugapowyej20kmButton.Value == 1
                   tracks = gpxread('szare-szeregi-akcja-pod-arsenalem.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
                  
              end
             end
             
             if app.PragaPnocButton_2.Value == 1
              if app.krtkaok5kmButton.Value == 1
            
            tracks = gpxread('trasa-2018-9-3-9-31.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dugapowyej20kmButton.Value == 1
                   tracks = gpxread('warsaw-21.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
                  
              end
             end
                
                 if app.PragaPoudnieButton_2.Value == 1
              if app.krtkaok5kmButton.Value == 1
            
            tracks = gpxread('hhhhha-aaaaa.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dugapowyej20kmButton.Value == 1
                   tracks = gpxread('06062017.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
                  
              end
           end
        end

        % Button pushed function: pokawskazwkiButton
        function pokawskazwkiButtonPushed(app, event)
            if app.TakButton.Value==1 && app.bardzolubiButton.Value==1 && app.nieczujsizmczonyButton.Value==1 && app.TakButton_2.Value==1 && app.takButton.Value==1 && app.wiecejniz3hButton.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Doskonale!!! Jesteś perfekcjonistą. Trening sprawia ci przyjemność. Utrzymuj dotychczasowe zaangażowanie w trening"
            elseif app.NieButton.Value==1 && app.bardzolubiButton.Value==1 && app.takbardzoButton.Value==1 && app.TakButton_2.Value==1 && app.takButton.Value==1 && app.hButton_2.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Bardzo dobrze!!! Widać że trening sprawia ci dużo radości. Jesteś bardzo pracowity i systematyczny. Starasz sie dążyć do określonych przez siebie celów. Dzieki tym cechom szybko osiągniesz to co sobie postanowiłeś. Dalej pracuj ciężko a osiągniesz najlepszą forme. Powodzenia!!!"
            elseif app.NieButton.Value==1 && app.nielubiButton.Value==1 && app.takbardzoButton.Value==1 && app.NieButton_2.Value==1 && app.nieButton.Value==1 && app.hButton.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Niestety przed Tobą jeszcze bardzo dużo pracy!!! Musisz być bardziej systematyczny i więcej czasu poświęcać na treningi to pomoże Ci poprawić kondycję. Dzieki ćwiczeniom będziesz widzał postepy a to sprawi że treningi będą przyjemnością a nie obowiązkiem"
            elseif app.TakButton.Value==1 && app.bardzolubiButton.Value==1 && app.jestemtrochzmczonyButton.Value==1 && app.TakButton_2.Value==1 && app.takButton.Value==1 && app.hButton_2.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Bardzo dobrze że ćwiczenia sprawiają ci radość. Musisz jeszcze trochę popracować nad swoją kondycją a pomoże ci w tym na pewno twoja systematyczność. Najważniejsze że widzisz postepy bo to sprawia że jesteś zmotywowany do dalszej pracy "
            elseif app.NieButton.Value==1 && app.nielubiButton.Value==1 && app.nieczujsizmczonyButton.Value==1 && app.NieButton_2.Value==1 && app.takButton.Value==1 && app.hButton.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Nie ćwiczysz systematycznie nie lubisz ćwiczyć a mimo to robisz postępy. Gratulacje!!! Jeśli dołożysz większe zaangażowanie w treningi może zostaniesz sławnym spotowcem "
            elseif app.TakButton.Value==1 && app.bardzolubiButton.Value==1 && app.jestemtrochzmczonyButton.Value==1 && app.NieButton_2.Value==1 && app.nieButton.Value==1 && app.hButton.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Nie widzisz postępu w treningach. To jezcze nie powód do zmartwień. Czasem wyniki przychodzą póżniej. Zachęcamy do częstszej pracy z aplikacją a problemy same się rozwiążą "
            elseif app.NieButton.Value==1 && app.bardzolubiButton.Value==1 && app.jestemtrochzmczonyButton.Value==1 && app.NieButton_2.Value==1 && app.nieButton.Value==1 && app.hButton.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Lubisz trenować a mimo to nie robisz tego systematycznie. Nie pozwól by lenistwo wzięło nad Tobą kontrolę. Ćwicz cześciej z naszą aplikacją "
            elseif app.TakButton.Value==1 && app.bardzolubiButton.Value==1 && app.jestemtrochzmczonyButton.Value==1 && app.NieButton_2.Value==1 && app.nieButton.Value==1 && app.wiecejniz3hButton.Value==1
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Jesteś na bardzo wysokim poziomie. Wielu może ci pozazdrościć. Wystarczy popracować na kondycją a będziesz perfekcjonistą "
            else
                app.TutajzobaczysznaszewskazwkiLabel.Text = "Cieszymy sie że trenujesz z naszą aplikacją. Widać że sa w pewnych elementach drobne niedociągnięcia ale dzięki wspólnym treningom na pewno uda sie je wyeliminować "
            end
        end

        % Button pushed function: OCEAPLIKACJButton
        function OCEAPLIKACJButtonPushed(app, event)
            if app.jestsuperButton.Value==1  
                app.CzeLabel.Text="Cieszymy sie że doceniasz naszą aplikację to dla nas powód do dumy"
            elseif app.bardzopomocnaButton.Value==1  
                app.CzeLabel.Text="Jest nam niezmiernie miło że nasza aplikacja pomaga ludziom. Dziękujemy!!!"
            elseif app.jestemrozczarowanyaButton.Value==1  
                app.CzeLabel.Text="To chyba jakiś żart. Jest nam niezwykle smutno"
            end
        end

        % Value changed function: wypelnijankietCheckBox
        function wypelnijankietCheckBoxValueChanged(app, event)
            value = app.wypelnijankietCheckBox.Value;
            switch value
                case 0
                    app.CzyjestezadowolonyztreninguButtonGroup.Visible = 'off'
                    app.CzyzrobiepostpButtonGroup.Visible='off'
                    app.CzywiczyszsystematycznieButtonGroup.Visible='off'
                    app.IlegodzindziennietrenujeszButtonGroup.Visible='off'
                    app.CzyczujeszsizmczonyButtonGroup.Visible='off'
                    app.CzylubiszwiczyButtonGroup.Visible='off'
                case 1
                    app.CzyjestezadowolonyztreninguButtonGroup.Visible = 'on'
                    app.CzyzrobiepostpButtonGroup.Visible='on'
                    app.CzywiczyszsystematycznieButtonGroup.Visible='on'
                    app.IlegodzindziennietrenujeszButtonGroup.Visible='on'
                    app.CzyczujeszsizmczonyButtonGroup.Visible='on'
                    app.CzylubiszwiczyButtonGroup.Visible='on'
                end
            
        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
             waga = app.WagakgEditField.Value
            wzrost = app.WzrostcmEditField.Value
            wiek = app.WiekEditField.Value
            wzrost_m = wzrost/100
%             waga1=app.wagaEditField.Value
%             wzrost2=app.wzrostEditField.Value
%             wiek2=app.wieczekEditField.Value
%            
            %obliczam bmi
            BMI = (waga)/((wzrost_m).^2)
            
        if app.KobietaButton.Value == 1
            if wiek>=18 && wiek<24
                if BMI>=19 && BMI<24
                    app.BMILamp.Color = 'g';
                    app.Label_4.Text = ''
                elseif BMI>=24 && BMI<34, BMI>=15 && BMI<19
                    app.BMILamp.Color = 'y';
                elseif BMI<15
                    app.BMILamp.Color = 'r';
                elseif BMI>=34 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=24 && wiek<34
                if BMI>=20 && BMI<25
                    app.BMILamp.Color = 'g';
                elseif BMI>=25 && BMI<35, BMI>=16 && BMI<20
                    app.BMILamp.Color = 'y';
                elseif BMI<16, BMI>=35 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=34 && wiek<44
                if BMI>=21 && BMI<26
                    app.BMILamp.Color = 'g';
                elseif BMI>=26 && BMI<36, BMI>=17 && BMI<21
                    app.BMILamp.Color = 'y';
                elseif BMI<17, BMI>=36 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=44 && wiek<54
                if BMI>=22 && BMI<27
                    app.BMILamp.Color = 'g';
                elseif BMI>=27 && BMI<37, BMI>=18 && BMI<22
                    app.BMILamp.Color = 'y';
                elseif BMI<18, BMI>=37 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=54 && wiek<64
                if BMI>=23 && BMI<28
                    app.BMILamp.Color = 'g';
                elseif BMI>=28 && BMI<38, BMI>=19 && BMI<23
                    app.BMILamp.Color = 'y';
                elseif BMI<19, BMI>=38 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=64 
                if BMI>=24 && BMI<29
                    app.BMILamp.Color = 'g';
                elseif BMI>=29 && BMI<39, BMI>=20 && BMI<24
                    app.BMILamp.Color = 'y';
                elseif BMI<20, BMI>=39 
                    app.BMILamp.Color = 'r';
                end
            end
        elseif app.MczyznaButton.Value == 1
            if wiek>=18 && wiek<24
                if BMI>=20 && BMI<25
                    app.BMILamp.Color = 'g';
                elseif BMI>=25 && BMI<35, BMI>=16 && BMI<20
                    app.BMILamp.Color = 'y';
                elseif BMI<16, BMI>=35 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=24 && wiek<34
                if BMI>=21 && BMI<26
                    app.BMILamp.Color = 'g';
                elseif BMI>=26 && BMI<36, BMI>=17 && BMI<21
                    app.BMILamp.Color = 'y';
                elseif BMI<17, BMI>=36
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=34 && wiek<44
                if BMI>=22 && BMI<27
                    app.BMILamp.Color = 'g';
                elseif BMI>=27 && BMI<37, BMI>=18 && BMI<22
                    app.BMILamp.Color = 'y';
                elseif BMI<18, BMI>=37 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=44 && wiek<54
                if BMI>=23 && BMI<28
                    app.BMILamp.Color = 'g';
                elseif BMI>=28 && BMI<38, BMI>=19 && BMI<23
                    app.BMILamp.Color = 'y';
                elseif BMI<19, BMI>=38 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=54 && wiek<64
                if BMI>=24 && BMI<29
                    app.BMILamp.Color = 'g';
                elseif BMI>=29 && BMI<39, BMI>=20 && BMI<24
                    app.BMILamp.Color = 'y';
                elseif BMI<20, BMI>=39 
                    app.BMILamp.Color = 'r';
                end
            elseif  wiek>=64 
                if BMI>=25 && BMI<30
                    app.BMILamp.Color = 'g';
                elseif BMI>=30 && BMI<40, BMI>=21 && BMI<25
                    app.BMILamp.Color = 'y';
                elseif BMI<21, BMI>=40 
                    app.BMILamp.Color = 'r';
                end
            end
        end
            
            global ZKA
            
            %podstawowa przemiaii
            if app.KobietaButton.Value == 1
                PPM = (10*waga)+(6.25*wzrost)-(5*wiek)-161;
            elseif app.MczyznaButton.Value == 1
                PPM = (10*waga)+(6.25*wzrost)-(5*wiek)+5;
            end
            
            %zapotrzebowanie kaloryczne
            if app.niskaaktywnosiedzcytrybyciaButton.Value == 1
                ZK = PPM*1.2;
            elseif app.redniaaktywnospacerytrening2razywtygButton.Value == 1
                ZK = PPM*1.375;
            elseif app.redniaaktywnotreningi35razywtygodniuButton.Value == 1
                ZK = PPM*1.55;
            elseif app.wysokaaktywnotreningi57razywtygodniuButton.Value == 1
                ZK = PPM*1.715;
            elseif app.pracafizyczna5dniwtygitreningi57razyButton.Value ==1
                ZK = PPM*1.9;
            end
            
            %zapotrzebowanie kaloryczne zgodnie z planami
            if app.UtrzymamasButton.Value == 1
               ZKA = ZK;
            elseif app.SchudnButton.Value == 1
                ZKA = ZK-((ZK/100)*15);
            elseif app.PrzytyButton.Value == 1
                ZKA = ZK+225;
            end
            
           A1 = 'Twoje BMI: ' 
           A2 = 'Twoje zapotrzebowanie kcal: ' 
           A3 = 'Obliczone wartości'
           
           app.Label_4.Text = sprintf(A3);
           app.Label_4.FontSize = 18;
           app.Label_4.Visible ='on';
           
           app.Label_5.Text = sprintf('%s', A1, num2str(BMI));
           app.Label_5.FontSize = 15;
           app.Label_5.Visible ='on';
           
           app.Label_6.Text = sprintf('%s', A2, num2str(ZKA));
           app.Label_6.FontSize = 15;
           app.Label_6.Visible ='on'; 
           
           
                       tabela = readtable('menu.xlsx')
zp1 = tabela.L_kalorii;
menu = tabela.menu;
menu2 = tabela.menu2;
menu3 = tabela.menu3;
menu4 = tabela.menu4;
menu5 = tabela.menu5;


%liczone zapotrzebowanie kaloryczne - zmiennna "szukane"
szukane = ZKA
i = floor(szukane/100)*100;
p = 1;
while i > zp1(p)
    p = p + 1;
end

jadlospis = menu(p);
jadlospis2 = menu2(p);
jadlospis3 = menu3(p);
jadlospis4 = menu4(p);
jadlospis5 = menu5(p);

app.Label_7.Text = jadlospis;
app.Label_7.Visible ='on';
app.Label_8.Text = jadlospis2;
app.Label_8.Visible ='on';
app.Label_9.Text = jadlospis3;
app.Label_9.Visible ='on';
app.Label_10.Text = jadlospis4;
app.Label_10.Visible ='on';
app.Label_11.Text = jadlospis5;
app.Label_11.Visible ='on';
        end

        % Button pushed function: ZnajdszlakrowerowyButton
        function ZnajdszlakrowerowyButtonPushed(app, event)
             if app.dolinaBiebrzyiNarwiiButton.Value == 1
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('szlak-rowerowy-strekowa-gora-las-toczylowo.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('szlak-rowerowy-bagno-wizna.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
             end
      
           if app.GrywitokrzyskieiokoliceButton.Value == 1
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('czerwony-szlak-rowerowy-dookola-zagnanska.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('niebieski-szlak-rowerowy-dookola-rakowa-i-jeziora-chancza.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
             end
              
               
              if app.NadZalewemWilanymButton.Value == 1
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('szlak-rowerowy-swiety-kamien.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('wokol-jeziora-druzno.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
              end
              
              if app.PogrzeKarpackieButton.Value == 1 
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('szlak-rowerowy-w-poszukiwaniu-kurhanow.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('niebieski-szlak-dookola-jaroslawia.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
              end
              
              
              if app.PolesieButton.Value == 1 
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('lesne-ostepy-chelmskiego-parku-krajobrazowego.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('szlak-lasy-sobiborskie.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
              end
              
               if app.PnocneMazuryButton.Value == 1
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('niebieski-szlak-rowerowy-w-nadlesnictwie-srokowo.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('szlak-rominckich-jeleni.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
               end
               
                if app.PuszczaBiaowieskaButton.Value == 1 
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('mala-petla-narewkowska.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('szlak-rowerowy-hajnowka-piaski.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
                end
                
                  if app.RoztoczeButton.Value == 1
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('brazowy-szlak-rowerowy-w-gminie-cieszanow.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('roztoczanski-szlak-rowerowy.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
                  end
                  
                   if app.SuwalszczyznaButton.Value == 1 
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('szlak-doliny-czarnej-hanczy.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('szlak-wokol-wigier-im-a-patli.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
                   end
                   
                   if app.WarmiaiokoliceButton.Value == 1 
              if app.dlapocztkujcychButton.Value == 1
            tracks = gpxread('kolejowym-szlakiem-lidzbark-warminski-orneta.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim)
              elseif app.dlazaawansowanychButton.Value == 1
                   tracks = gpxread('szlak-rowerowy-nr-1-w-gminie-lidzbark-warminski-kolor-czerwony.gpx', 'Index', 1:2);
           webmap('openstreetmap')
           wmline(tracks)
          [latlim, lonlim] = geoquadline(tracks(1).Latitude, tracks(1).Longitude);
           wmlimits(latlim, lonlim) 
              end
              end
        end

        % Button pushed function: WylosujciekawostkButton
        function WylosujciekawostkButtonPushed(app, event)
            T = readtable('proba.xlsx'); 
n = randi(10)
ciekawostka = T{n,1}
app.TutajwywietlisiTwojaciekawostkaLabel.Text=ciekawostka
        end

        % Selection changed function: 
        % IlegodzindziennietrenujeszButtonGroup
        function IlegodzindziennietrenujeszButtonGroupSelectionChanged(app, event)
            selectedButton = app.IlegodzindziennietrenujeszButtonGroup.SelectedObject;
            
        end

        % Selection changed function: 
        % WybierzpartiktraCiinteresujeButtonGroup
        function WybierzpartiktraCiinteresujeButtonGroupSelectionChanged(app, event)
            selectedButton = app.WybierzpartiktraCiinteresujeButtonGroup.SelectedObject;

        end

        % Button pushed function: WczfilmButton
        function WczfilmButtonPushed(app, event)
            if app.ABSButton.Value == 1
                implay('brzuch.mp4')
            elseif app.PoladkiButton.Value == 1
                implay('posladki.mp4')
            elseif app.UdaButton.Value == 1
                implay('uda.mp4')
            elseif app.CaeciaoButton.Value == 1
                implay('calecialo.mp4')
            elseif app.RozgrzewkaButton.Value == 1
                implay('rozgrzewka.mp4')
            elseif app.RozciganiepotreninguButton.Value == 1
                implay('rozciaganie.mp4')
            elseif app.TABATAButton.Value == 1
                implay('tabata.mp4')
            else app.RamionaButton.Value == 1
                implay('ramiona.mp4')
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FITNESSappUIFigure = uifigure('Visible', 'off');
            app.FITNESSappUIFigure.Name = 'FITNESSapp';
            app.TabGroup = uitabgroup(app.FITNESSappUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LogowanieTab = uitab(app.TabGroup);
            app.LogowanieTab.Title = 'Logowanie';
            app.WitajLabel = uilabel(app.LogowanieTab);
            app.WitajLabel.Text = 'Witaj!  ';
            app.PrzedrozpoczciemzakontoLabel = uilabel(app.LogowanieTab);
            app.PrzedrozpoczciemzakontoLabel.Text = 'Przed rozpoczęciem - załóż konto:';
            app.Panel = uipanel(app.LogowanieTab);
            app.ImiEditFieldLabel = uilabel(app.Panel);
            app.ImiEditFieldLabel.Text = 'Imię';
            app.ImiEditField = uieditfield(app.Panel, 'text');
            app.NazwiskoEditFieldLabel = uilabel(app.Panel);
            app.NazwiskoEditFieldLabel.Text = 'Nazwisko';
            app.NazwiskoEditField = uieditfield(app.Panel, 'text');
            app.NickEditFieldLabel = uilabel(app.Panel);
            app.NickEditFieldLabel.Text = 'Nick';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.KobietaButton = uiradiobutton(app.ButtonGroup);
            app.KobietaButton.Text = 'Kobieta';
            app.KobietaButton.Value = true;
            app.MczyznaButton = uiradiobutton(app.ButtonGroup);
            app.MczyznaButton.Text = 'Mężczyzna';
            app.NickEditField = uieditfield(app.Panel, 'text');
            app.ZapiszButton = uibutton(app.LogowanieTab, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Icon = 'zapisz.png';
            app.ZapiszButton.Text = 'Zapisz';
            app.Button_2 = uibutton(app.LogowanieTab, 'push');
            app.Button_2.Icon = 'LOGO.jpg';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.UITable = uitable(app.LogowanieTab);
            app.UITable.ColumnName = {'Imię'; 'Nazwisko'; 'Płeć'; 'Column 4'};
            app.UITable.RowName = {};
            app.Lamp = uilamp(app.LogowanieTab);
            app.Button = uibutton(app.LogowanieTab, 'push');
            app.Button.Icon = 'LOGO.jpg';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Label_2 = uilabel(app.LogowanieTab);
            app.Label_2.Text = '';
            app.Label = uilabel(app.LogowanieTab);
            app.Label.Text = '';
            app.WczytajButton = uibutton(app.LogowanieTab, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Visible = 'off';
            app.WczytajButton.Text = 'Wczytaj';
            app.BMITab = uitab(app.TabGroup);
            app.BMITab.Title = 'BMI';
            app.WagakgEditFieldLabel = uilabel(app.BMITab);
            app.WagakgEditFieldLabel.Text = 'Waga [kg]';
            app.WzrostcmEditFieldLabel = uilabel(app.BMITab);
            app.WzrostcmEditFieldLabel.Text = 'Wzrost [cm]';
            app.WiekEditFieldLabel = uilabel(app.BMITab);
            app.WiekEditFieldLabel.Text = 'Wiek';
            app.BMILampLabel = uilabel(app.BMITab);
            app.BMILampLabel.Text = 'BMI';
            app.Label_6 = uilabel(app.BMITab);
            app.Label_6.Visible = 'off';
            app.Label_5 = uilabel(app.BMITab);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = 'Label2';
            app.Label_4 = uilabel(app.BMITab);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = 'Label3';
            app.BMILamp = uilamp(app.BMITab);
            app.ObliczButton = uibutton(app.BMITab, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.PlanujeButtonGroup = uibuttongroup(app.BMITab);
            app.PlanujeButtonGroup.Title = 'Planuje';
            app.PrzytyButton = uiradiobutton(app.PlanujeButtonGroup);
            app.PrzytyButton.Text = 'Przytyć';
            app.SchudnButton = uiradiobutton(app.PlanujeButtonGroup);
            app.SchudnButton.Text = 'Schudnąć';
            app.UtrzymamasButton = uiradiobutton(app.PlanujeButtonGroup);
            app.UtrzymamasButton.Text = 'Utrzymać masę';
            app.UtrzymamasButton.Value = true;
            app.WiekEditField = uieditfield(app.BMITab, 'numeric');
            app.WzrostcmEditField = uieditfield(app.BMITab, 'numeric');
            app.WagakgEditField = uieditfield(app.BMITab, 'numeric');
            app.AktywnofizycznaButtonGroup = uibuttongroup(app.BMITab);
            app.AktywnofizycznaButtonGroup.Title = 'Aktywność fizyczna';
            app.pracafizyczna5dniwtygitreningi57razyButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.pracafizyczna5dniwtygitreningi57razyButton.Text = 'praca fizyczna 5 dni w tyg i treningi 5-7 razy';
            app.wysokaaktywnotreningi57razywtygodniuButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.wysokaaktywnotreningi57razywtygodniuButton.Text = 'wysoka aktywność (treningi 5-7 razy w tygodniu)';
            app.redniaaktywnotreningi35razywtygodniuButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.redniaaktywnotreningi35razywtygodniuButton.Text = 'średnia aktywność (treningi 3-5 razy w tygodniu)';
            app.redniaaktywnospacerytrening2razywtygButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.redniaaktywnospacerytrening2razywtygButton.Text = 'średnia aktywność (spacery, trening 2 razy w tyg)';
            app.niskaaktywnosiedzcytrybyciaButton = uiradiobutton(app.AktywnofizycznaButtonGroup);
            app.niskaaktywnosiedzcytrybyciaButton.Text = 'niska aktywność, siedzący tryb życia';
            app.niskaaktywnosiedzcytrybyciaButton.Value = true;
            app.PeButtonGroup = uibuttongroup(app.BMITab);
            app.PeButtonGroup.Title = 'Płeć';
            app.KobietaButton_2 = uiradiobutton(app.PeButtonGroup);
            app.KobietaButton_2.Text = 'Kobieta';
            app.KobietaButton_2.Value = true;
            app.MczyznaButton_2 = uiradiobutton(app.PeButtonGroup);
            app.MczyznaButton_2.Text = 'Mężczyzna';
            app.ObliczswojeBMIizapotrzebowaniekaloryczneLabel = uilabel(app.BMITab);
            app.ObliczswojeBMIizapotrzebowaniekaloryczneLabel.Text = 'Oblicz swoje BMI i zapotrzebowanie kaloryczne';
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Label_11 = uilabel(app.MenuTab);
            app.Label_11.Visible = 'off';
            app.Label_11.Text = 'Label4';
            app.Label_10 = uilabel(app.MenuTab);
            app.Label_10.Visible = 'off';
            app.Label_10.Text = 'Label4';
            app.Label_9 = uilabel(app.MenuTab);
            app.Label_9.Visible = 'off';
            app.Label_9.Text = 'Label4';
            app.Label_8 = uilabel(app.MenuTab);
            app.Label_8.Visible = 'off';
            app.Label_8.Text = 'Label4';
            app.Label_7 = uilabel(app.MenuTab);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = 'Label4';
            app.PrzykadowyjadospisdlaTwojegozapotrzebowaniakalorycznegoLabel = uilabel(app.MenuTab);
            app.PrzykadowyjadospisdlaTwojegozapotrzebowaniakalorycznegoLabel.Text = 'Przykładowy jadłospis, dla Twojego zapotrzebowania kalorycznego';
            app.Image13 = uiimage(app.MenuTab);
            app.Image13.ImageSource = 'owoce-kolorowanki-BIG-22208.jpg';
            app.SiownieTab = uitab(app.TabGroup);
            app.SiownieTab.Title = 'Siłownie';
            app.DzielnicaButtonGroup = uibuttongroup(app.SiownieTab);
            app.DzielnicaButtonGroup.Title = 'Dzielnica';
            app.BemowoButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.BemowoButton.Text = 'Bemowo';
            app.BemowoButton.Value = true;
            app.BiaokaButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.BiaokaButton.Text = 'Białołęka';
            app.BielanyButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.BielanyButton.Text = 'Bielany';
            app.MokotwButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.MokotwButton.Text = 'Mokotów';
            app.OchotaButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.OchotaButton.Text = 'Ochota';
            app.PragaPoudnieButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.PragaPoudnieButton.Text = 'Praga-Południe';
            app.PragaPnocButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.PragaPnocButton.Text = 'Praga-Północ';
            app.RembertwButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.RembertwButton.Text = 'Rembertów';
            app.rdmiecieButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.rdmiecieButton.Text = 'Śródmieście';
            app.TargwekButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.TargwekButton.Text = 'Targówek';
            app.UrsusButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.UrsusButton.Text = 'Ursus';
            app.UrsynwButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.UrsynwButton.Text = 'Ursynów';
            app.WawerButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.WawerButton.Text = 'Wawer';
            app.WesoaButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.WesoaButton.Text = 'Wesoła';
            app.WilanwButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.WilanwButton.Text = 'Wilanów';
            app.WochyButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.WochyButton.Text = 'Włochy';
            app.WolaButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.WolaButton.Text = 'Wola';
            app.oliborzButton = uiradiobutton(app.DzielnicaButtonGroup);
            app.oliborzButton.Text = 'Żoliborz';
            app.PokasiownieButton = uibutton(app.SiownieTab, 'push');
            app.PokasiownieButton.ButtonPushedFcn = createCallbackFcn(app, @PokasiownieButtonPushed, true);
            app.PokasiownieButton.Text = 'Pokaż siłownie';
            app.UITable4 = uitable(app.SiownieTab);
            app.UITable4.ColumnName = {'Nazwa'; 'Adres'};
            app.UITable4.RowName = {};
            app.Image3 = uiimage(app.SiownieTab);
            app.Image3.ImageSource = 'kulturystyka-ruchomy-obrazek-0055.gif';
            app.SiowniewWarszawieLabel = uilabel(app.SiownieTab);
            app.SiowniewWarszawieLabel.Text = 'Siłownie w Warszawie ';
            app.SzlakirowerowewersjamiejskaTab = uitab(app.TabGroup);
            app.SzlakirowerowewersjamiejskaTab.Title = 'Szlaki rowerowe - wersja miejska';
            app.ZnajdszlakButton = uibutton(app.SzlakirowerowewersjamiejskaTab, 'push');
            app.ZnajdszlakButton.ButtonPushedFcn = createCallbackFcn(app, @ZnajdszlakButtonPushed, true);
            app.ZnajdszlakButton.Text = 'Znajdź szlak';
            app.RodzajtrasyButtonGroup = uibuttongroup(app.SzlakirowerowewersjamiejskaTab);
            app.RodzajtrasyButtonGroup.Title = 'Rodzaj trasy';
            app.krtkaok5kmButton = uiradiobutton(app.RodzajtrasyButtonGroup);
            app.krtkaok5kmButton.Text = 'krótka ok 5 km';
            app.krtkaok5kmButton.Value = true;
            app.dugapowyej20kmButton = uiradiobutton(app.RodzajtrasyButtonGroup);
            app.dugapowyej20kmButton.Text = 'długa powyżej 20 km';
            app.DzielniceWarszawyButtonGroup = uibuttongroup(app.SzlakirowerowewersjamiejskaTab);
            app.DzielniceWarszawyButtonGroup.Title = 'Dzielnice Warszawy';
            app.rdmiecieButton_2 = uiradiobutton(app.DzielniceWarszawyButtonGroup);
            app.rdmiecieButton_2.Text = 'Śródmieście';
            app.rdmiecieButton_2.Value = true;
            app.OchotaButton_2 = uiradiobutton(app.DzielniceWarszawyButtonGroup);
            app.OchotaButton_2.Text = 'Ochota';
            app.WolaButton_2 = uiradiobutton(app.DzielniceWarszawyButtonGroup);
            app.WolaButton_2.Text = 'Wola';
            app.MokotwButton_2 = uiradiobutton(app.DzielniceWarszawyButtonGroup);
            app.MokotwButton_2.Text = 'Mokotów';
            app.PragaPoudnieButton_2 = uiradiobutton(app.DzielniceWarszawyButtonGroup);
            app.PragaPoudnieButton_2.Text = 'Praga Południe';
            app.PragaPnocButton_2 = uiradiobutton(app.DzielniceWarszawyButtonGroup);
            app.PragaPnocButton_2.Text = 'Praga Północ';
            app.Image5 = uiimage(app.SzlakirowerowewersjamiejskaTab);
            app.Image5.ImageSource = 'giphy.gif';
            app.Image6 = uiimage(app.SzlakirowerowewersjamiejskaTab);
            app.Image6.ImageSource = '1200px-Warszawa_podzial_administracyjny_2002.svg.png';
            app.SzlakirowerowewersjawakacyjnaTab = uitab(app.TabGroup);
            app.SzlakirowerowewersjawakacyjnaTab.Title = 'Szlaki rowerowe - wersja wakacyjna ';
            app.ZnajdszlakrowerowyButton = uibutton(app.SzlakirowerowewersjawakacyjnaTab, 'push');
            app.ZnajdszlakrowerowyButton.ButtonPushedFcn = createCallbackFcn(app, @ZnajdszlakrowerowyButtonPushed, true);
            app.ZnajdszlakrowerowyButton.Text = 'Znajdź szlak rowerowy';
            app.Image = uiimage(app.SzlakirowerowewersjawakacyjnaTab);
            app.Image.ImageSource = 'GifyAgusi.pl - _img_2020_08_31_gify-i-obrazki_Gify rower.gif';
            app.RodzajtrasyButtonGroup_2 = uibuttongroup(app.SzlakirowerowewersjawakacyjnaTab);
            app.RodzajtrasyButtonGroup_2.Title = 'Rodzaj trasy';
            app.dlapocztkujcychButton = uiradiobutton(app.RodzajtrasyButtonGroup_2);
            app.dlapocztkujcychButton.Text = 'dla początkujących';
            app.dlapocztkujcychButton.Value = true;
            app.dlazaawansowanychButton = uiradiobutton(app.RodzajtrasyButtonGroup_2);
            app.dlazaawansowanychButton.Text = 'dla zaawansowanych';
            app.MiejscawPolsceButtonGroup = uibuttongroup(app.SzlakirowerowewersjawakacyjnaTab);
            app.MiejscawPolsceButtonGroup.Title = 'Miejsca w Polsce';
            app.dolinaBiebrzyiNarwiiButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.dolinaBiebrzyiNarwiiButton.Text = 'dolina Biebrzy i Narwii';
            app.dolinaBiebrzyiNarwiiButton.Value = true;
            app.GrywitokrzyskieiokoliceButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.GrywitokrzyskieiokoliceButton.Text = 'Góry Świętokrzyskie i okolice';
            app.NadZalewemWilanymButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.NadZalewemWilanymButton.Text = 'Nad Zalewem Wiślanym';
            app.PogrzeKarpackieButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.PogrzeKarpackieButton.Text = 'Pogórze Karpackie';
            app.PolesieButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.PolesieButton.Text = 'Polesie';
            app.PnocneMazuryButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.PnocneMazuryButton.Text = 'Północne Mazury';
            app.PuszczaBiaowieskaButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.PuszczaBiaowieskaButton.Text = 'Puszcza Białowieska';
            app.RoztoczeButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.RoztoczeButton.Text = 'Roztocze';
            app.SuwalszczyznaButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.SuwalszczyznaButton.Text = 'Suwalszczyzna';
            app.WarmiaiokoliceButton = uiradiobutton(app.MiejscawPolsceButtonGroup);
            app.WarmiaiokoliceButton.Text = 'Warmia i okolice';
            app.EditField = uieditfield(app.SzlakirowerowewersjawakacyjnaTab, 'text');
            app.EditField.Value = 'Spędź wakacje aktywnie z naszą apikacją!';
            app.Image2 = uiimage(app.SzlakirowerowewersjawakacyjnaTab);
            app.Image2.ImageSource = 'trasa1.gif';
            app.wiczeniaTab = uitab(app.TabGroup);
            app.wiczeniaTab.Title = 'Ćwiczenia';
            app.WybierzpartiktraCiinteresujeButtonGroup = uibuttongroup(app.wiczeniaTab);
            app.WybierzpartiktraCiinteresujeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WybierzpartiktraCiinteresujeButtonGroupSelectionChanged, true);
            app.WybierzpartiktraCiinteresujeButtonGroup.Title = 'Wybierz partię, która Cię interesuje:';
            app.ABSButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.ABSButton.Text = 'ABS';
            app.ABSButton.Value = true;
            app.RamionaButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.RamionaButton.Text = 'Ramiona';
            app.UdaButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.UdaButton.Text = 'Uda';
            app.PoladkiButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.PoladkiButton.Text = 'Pośladki';
            app.CaeciaoButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.CaeciaoButton.Text = 'Całe ciało';
            app.TABATAButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.TABATAButton.Text = 'TABATA';
            app.RozgrzewkaButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.RozgrzewkaButton.Text = 'Rozgrzewka';
            app.RozciganiepotreninguButton = uiradiobutton(app.WybierzpartiktraCiinteresujeButtonGroup);
            app.RozciganiepotreninguButton.Text = 'Rozciąganie po treningu';
            app.WczfilmButton = uibutton(app.wiczeniaTab, 'push');
            app.WczfilmButton.ButtonPushedFcn = createCallbackFcn(app, @WczfilmButtonPushed, true);
            app.WczfilmButton.Text = 'Włącz film';
            app.TextArea = uitextarea(app.wiczeniaTab);
            app.TextArea.Value = {'Pamiętaj aby przed treningiem wykonać rozgrzewkę i rozciagnąć się po zakończeniu ćwiczeń!'};
            app.Image14 = uiimage(app.wiczeniaTab);
            app.Image14.ImageSource = '50316882_229963717885760_2615288032726614016_n.gif';
            app.CiekawostkiTab = uitab(app.TabGroup);
            app.CiekawostkiTab.Title = 'Ciekawostki';
            app.WylosujciekawostkButton = uibutton(app.CiekawostkiTab, 'push');
            app.WylosujciekawostkButton.ButtonPushedFcn = createCallbackFcn(app, @WylosujciekawostkButtonPushed, true);
            app.WylosujciekawostkButton.Text = 'Wylosuj ciekawostkę!';
            app.TutajwywietlisiTwojaciekawostkaLabel = uilabel(app.CiekawostkiTab);
            app.TutajwywietlisiTwojaciekawostkaLabel.Text = 'Tutaj wyświetli się Twoja ciekawostka!';
            app.Image8 = uiimage(app.CiekawostkiTab);
            app.Image8.ImageSource = 'Podnoszenie-Ciezarow-82245.gif';
            app.AnkietaTab = uitab(app.TabGroup);
            app.AnkietaTab.Title = 'Ankieta';
            app.CzyjestezadowolonyztreninguButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzyjestezadowolonyztreninguButtonGroup.Title = 'Czy jesteś zadowolony z treningu?';
            app.takButton = uiradiobutton(app.CzyjestezadowolonyztreninguButtonGroup);
            app.takButton.Text = 'tak';
            app.takButton.Value = true;
            app.nieButton = uiradiobutton(app.CzyjestezadowolonyztreninguButtonGroup);
            app.nieButton.Text = 'nie';
            app.trudnopowiedzieButton = uiradiobutton(app.CzyjestezadowolonyztreninguButtonGroup);
            app.trudnopowiedzieButton.Text = 'trudno powiedzieć';
            app.IlegodzindziennietrenujeszButtonGroup = uibuttongroup(app.AnkietaTab);
            app.IlegodzindziennietrenujeszButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IlegodzindziennietrenujeszButtonGroupSelectionChanged, true);
            app.IlegodzindziennietrenujeszButtonGroup.Title = 'Ile godzin dziennie trenujesz?';
            app.hButton = uiradiobutton(app.IlegodzindziennietrenujeszButtonGroup);
            app.hButton.Text = '1h';
            app.hButton.Value = true;
            app.hButton_2 = uiradiobutton(app.IlegodzindziennietrenujeszButtonGroup);
            app.hButton_2.Text = '2-3h';
            app.wiecejniz3hButton = uiradiobutton(app.IlegodzindziennietrenujeszButtonGroup);
            app.wiecejniz3hButton.Text = 'wiecej niz 3h';
            app.CzywiczyszsystematycznieButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzywiczyszsystematycznieButtonGroup.Title = 'Czy ćwiczysz systematycznie?';
            app.TakButton = uiradiobutton(app.CzywiczyszsystematycznieButtonGroup);
            app.TakButton.Text = 'Tak';
            app.TakButton.Value = true;
            app.NieButton = uiradiobutton(app.CzywiczyszsystematycznieButtonGroup);
            app.NieButton.Text = 'Nie';
            app.CzyzrobiepostpButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzyzrobiepostpButtonGroup.Title = 'Czy zrobiłeś postęp?';
            app.TakButton_2 = uiradiobutton(app.CzyzrobiepostpButtonGroup);
            app.TakButton_2.Text = 'Tak';
            app.TakButton_2.Value = true;
            app.NieButton_2 = uiradiobutton(app.CzyzrobiepostpButtonGroup);
            app.NieButton_2.Text = 'Nie';
            app.CzyczujeszsizmczonyButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzyczujeszsizmczonyButtonGroup.Title = 'Czy czujesz się zmęczony?';
            app.takbardzoButton = uiradiobutton(app.CzyczujeszsizmczonyButtonGroup);
            app.takbardzoButton.Text = 'tak bardzo';
            app.takbardzoButton.Value = true;
            app.jestemtrochzmczonyButton = uiradiobutton(app.CzyczujeszsizmczonyButtonGroup);
            app.jestemtrochzmczonyButton.Text = 'jestem trochę zmęczony';
            app.nieczujsizmczonyButton = uiradiobutton(app.CzyczujeszsizmczonyButtonGroup);
            app.nieczujsizmczonyButton.Text = 'nie czuję się zmęczony';
            app.CzylubiszwiczyButtonGroup = uibuttongroup(app.AnkietaTab);
            app.CzylubiszwiczyButtonGroup.Title = 'Czy lubisz ćwiczyć?';
            app.bardzolubiButton = uiradiobutton(app.CzylubiszwiczyButtonGroup);
            app.bardzolubiButton.Text = 'bardzo lubię';
            app.bardzolubiButton.Value = true;
            app.nielubiButton = uiradiobutton(app.CzylubiszwiczyButtonGroup);
            app.nielubiButton.Text = {'nie lubię'; ''};
            app.pokawskazwkiButton = uibutton(app.AnkietaTab, 'push');
            app.pokawskazwkiButton.ButtonPushedFcn = createCallbackFcn(app, @pokawskazwkiButtonPushed, true);
            app.pokawskazwkiButton.Text = 'pokaż wskazówki';
            app.jakpodobacisieaplikacjaButtonGroup = uibuttongroup(app.AnkietaTab);
            app.jakpodobacisieaplikacjaButtonGroup.Title = 'jak podoba ci sie aplikacja?';
            app.jestsuperButton = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.jestsuperButton.Text = 'jest super';
            app.jestsuperButton.Value = true;
            app.bardzopomocnaButton = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.bardzopomocnaButton.Text = 'bardzo pomocna';
            app.jestemrozczarowanyaButton = uiradiobutton(app.jakpodobacisieaplikacjaButtonGroup);
            app.jestemrozczarowanyaButton.Text = 'jestem rozczarowany/a';
            app.OCEAPLIKACJButton = uibutton(app.jakpodobacisieaplikacjaButtonGroup, 'push');
            app.OCEAPLIKACJButton.ButtonPushedFcn = createCallbackFcn(app, @OCEAPLIKACJButtonPushed, true);
            app.OCEAPLIKACJButton.Text = 'OCEŃ APLIKACJĘ';
            app.CzeLabel = uilabel(app.jakpodobacisieaplikacjaButtonGroup);
            app.CzeLabel.Text = 'Cześć';
            app.TutajzobaczysznaszewskazwkiLabel = uilabel(app.AnkietaTab);
            app.TutajzobaczysznaszewskazwkiLabel.Text = 'Tutaj zobaczysz nasze wskazówki!';
            app.wypelnijankietCheckBox = uicheckbox(app.AnkietaTab);
            app.wypelnijankietCheckBox.ValueChangedFcn = createCallbackFcn(app, @wypelnijankietCheckBoxValueChanged, true);
            app.wypelnijankietCheckBox.Text = 'wypelnij ankietę';
            app.wypelnijankietCheckBox.Value = true;
            app.RankingTab = uitab(app.TabGroup);
            app.RankingTab.Title = 'Ranking';
            app.Image7 = uiimage(app.RankingTab);
            app.Image7.ImageSource = '154-1546736_body-builder-cartoon-gif-clipart.png';
            app.ilKg = uieditfield(app.RankingTab, 'numeric');
            app.RANKINGUYTKOWNIKWLabel = uilabel(app.RankingTab);
            app.RANKINGUYTKOWNIKWLabel.Text = 'RANKING UŻYTKOWNIKÓW';
            app.PodajswjnickEditFieldLabel = uilabel(app.RankingTab);
            app.PodajswjnickEditFieldLabel.Text = 'Podaj swój nick:';
            app.PodajswjnickEditField = uieditfield(app.RankingTab, 'text');
            app.Lamp_R = uilamp(app.RankingTab);
            app.IlekilogramwudaoCisischudnLabel = uilabel(app.RankingTab);
            app.IlekilogramwudaoCisischudnLabel.Text = 'Ile kilogramów udało ci się schudnąć?';
            app.PokawynikiButton = uibutton(app.RankingTab, 'push');
            app.PokawynikiButton.ButtonPushedFcn = createCallbackFcn(app, @PokawynikiButtonPushed, true);
            app.PokawynikiButton.Text = 'Pokaż wyniki';
            app.Label_3 = uilabel(app.RankingTab);
            app.Label_3.Text = '';
            app.UITable3 = uitable(app.RankingTab);
            app.UITable3.ColumnName = {'Nick'; 'IloscKilogramow'};
            app.UITable3.RowName = {};
            % Show the figure after all components are created
            app.FITNESSappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ostateczna

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FITNESSappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FITNESSappUIFigure)
        end
    end
end